classdef MVsim_10 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        InitiateandRunButton            matlab.ui.control.Button
        RerunButton                     matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        TopologyTab                     matlab.ui.container.Tab
        Image_linker_LigandB_2          matlab.ui.control.Image
        Image_linker_LigandB_1          matlab.ui.control.Image
        Image_linker_LigandC_2          matlab.ui.control.Image
        Image_linker_LigandC_1          matlab.ui.control.Image
        Image_linker_LigandA_2          matlab.ui.control.Image
        Image_linker_LigandA_1          matlab.ui.control.Image
        iButton_Receptor_3              matlab.ui.control.Image
        iButton_Receptor_2              matlab.ui.control.Image
        iButton_Receptor_1              matlab.ui.control.Image
        iButton_LigandC_3               matlab.ui.control.Image
        iButton_LigandC_2               matlab.ui.control.Image
        iButton_LigandC_1               matlab.ui.control.Image
        iButton_LigandB_3               matlab.ui.control.Image
        iButton_LigandB_2               matlab.ui.control.Image
        iButton_LigandB_1               matlab.ui.control.Image
        iButton_LigandA_3               matlab.ui.control.Image
        iButton_LigandA_2               matlab.ui.control.Image
        iButton_LigandA_1               matlab.ui.control.Image
        TitleImage_Receptor             matlab.ui.control.Image
        TitleImage_LigandC              matlab.ui.control.Image
        TitleImage_LigandB              matlab.ui.control.Image
        TitleImage_LigandA              matlab.ui.control.Image
        Receptor_linker_2               matlab.ui.control.Image
        Receptor_linker_1               matlab.ui.control.Image
        Image_Ligand_3                  matlab.ui.control.Image
        LigandATab                      matlab.ui.container.Tab
        MWLabel                         matlab.ui.control.Label
        PersistanceLength2_LA           matlab.ui.control.NumericEditField
        EditField_8Label                matlab.ui.control.Label
        PersistanceLength1_LA           matlab.ui.control.NumericEditField
        EditField_7Label                matlab.ui.control.Label
        LinkerLength2_LA                matlab.ui.control.NumericEditField
        LinkerLength1_LA                matlab.ui.control.NumericEditField
        UnitDiameter3_LA                matlab.ui.control.NumericEditField
        EditField_4Label                matlab.ui.control.Label
        UnitDiameter2_LA                matlab.ui.control.NumericEditField
        EditField_3Label                matlab.ui.control.Label
        UnitDiameter1_LA                matlab.ui.control.NumericEditField
        EditField_2Label                matlab.ui.control.Label
        MolecularWeight_LA              matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        Copy_Values_A                   matlab.ui.control.CheckBox
        Panel_3                         matlab.ui.container.Panel
        GridLayout2_3                   matlab.ui.container.GridLayout
        Koff_LigandA_R3_L3              matlab.ui.control.NumericEditField
        Kon_LigandA_R3_L3               matlab.ui.control.NumericEditField
        Koff_LigandA_R2_L3              matlab.ui.control.NumericEditField
        Kon_LigandA_R2_L3               matlab.ui.control.NumericEditField
        Koff_LigandA_R1_L3              matlab.ui.control.NumericEditField
        Kon_LigandA_R1_L3               matlab.ui.control.NumericEditField
        Panel_2                         matlab.ui.container.Panel
        GridLayout2_2                   matlab.ui.container.GridLayout
        Koff_LigandA_R3_L2              matlab.ui.control.NumericEditField
        Kon_LigandA_R3_L2               matlab.ui.control.NumericEditField
        Koff_LigandA_R2_L2              matlab.ui.control.NumericEditField
        Kon_LigandA_R2_L2               matlab.ui.control.NumericEditField
        Koff_LigandA_R1_L2              matlab.ui.control.NumericEditField
        Kon_LigandA_R1_L2               matlab.ui.control.NumericEditField
        Panel                           matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        Koff_LigandA_R3_L1              matlab.ui.control.NumericEditField
        Kon_LigandA_R3_L1               matlab.ui.control.NumericEditField
        Koff_LigandA_R2_L1              matlab.ui.control.NumericEditField
        Kon_LigandA_R2_L1               matlab.ui.control.NumericEditField
        Koff_LigandA_R1_L1              matlab.ui.control.NumericEditField
        Kon_LigandA_R1_L1               matlab.ui.control.NumericEditField
        Image_Tab_LigandA               matlab.ui.control.Image
        LigandBTab                      matlab.ui.container.Tab
        Copy_Values_B                   matlab.ui.control.CheckBox
        MWLabel_2                       matlab.ui.control.Label
        PersistanceLength2_LB           matlab.ui.control.NumericEditField
        EditField_8Label_2              matlab.ui.control.Label
        PersistanceLength1_LB           matlab.ui.control.NumericEditField
        EditField_7Label_2              matlab.ui.control.Label
        UnitDiameter3_LB                matlab.ui.control.NumericEditField
        EditField_4Label_2              matlab.ui.control.Label
        UnitDiameter2_LB                matlab.ui.control.NumericEditField
        EditField_3Label_2              matlab.ui.control.Label
        UnitDiameter1_LB                matlab.ui.control.NumericEditField
        EditField_2Label_2              matlab.ui.control.Label
        MolecularWeight_LB              matlab.ui.control.NumericEditField
        EditFieldLabel_2                matlab.ui.control.Label
        LinkerLength2_LB                matlab.ui.control.NumericEditField
        LinkerLength1_LB                matlab.ui.control.NumericEditField
        CheckBox_B                      matlab.ui.control.CheckBox
        Panel_6                         matlab.ui.container.Panel
        GridLayout2_6                   matlab.ui.container.GridLayout
        Koff_LigandB_R3_L3              matlab.ui.control.NumericEditField
        Kon_LigandB_R3_L3               matlab.ui.control.NumericEditField
        Koff_LigandB_R2_L3              matlab.ui.control.NumericEditField
        Kon_LigandB_R2_L3               matlab.ui.control.NumericEditField
        Koff_LigandB_R1_L3              matlab.ui.control.NumericEditField
        Kon_LigandB_R1_L3               matlab.ui.control.NumericEditField
        Panel_5                         matlab.ui.container.Panel
        GridLayout2_5                   matlab.ui.container.GridLayout
        Koff_LigandB_R3_L2              matlab.ui.control.NumericEditField
        Kon_LigandB_R3_L2               matlab.ui.control.NumericEditField
        Koff_LigandB_R2_L2              matlab.ui.control.NumericEditField
        Kon_LigandB_R2_L2               matlab.ui.control.NumericEditField
        Koff_LigandB_R1_L2              matlab.ui.control.NumericEditField
        Kon_LigandB_R1_L2               matlab.ui.control.NumericEditField
        Panel_4                         matlab.ui.container.Panel
        GridLayout2_4                   matlab.ui.container.GridLayout
        Koff_LigandB_R3_L1              matlab.ui.control.NumericEditField
        Kon_LigandB_R3_L1               matlab.ui.control.NumericEditField
        Koff_LigandB_R2_L1              matlab.ui.control.NumericEditField
        Kon_LigandB_R2_L1               matlab.ui.control.NumericEditField
        Koff_LigandB_R1_L1              matlab.ui.control.NumericEditField
        Kon_LigandB_R1_L1               matlab.ui.control.NumericEditField
        Image_Tab_LigandB               matlab.ui.control.Image
        LigandCTab                      matlab.ui.container.Tab
        Copy_Values_C                   matlab.ui.control.CheckBox
        MWLabel_3                       matlab.ui.control.Label
        PersistanceLength2_LC           matlab.ui.control.NumericEditField
        EditField_8Label_3              matlab.ui.control.Label
        PersistanceLength1_LC           matlab.ui.control.NumericEditField
        EditField_7Label_3              matlab.ui.control.Label
        UnitDiameter3_LC                matlab.ui.control.NumericEditField
        EditField_4Label_3              matlab.ui.control.Label
        UnitDiameter2_LC                matlab.ui.control.NumericEditField
        EditField_3Label_3              matlab.ui.control.Label
        UnitDiameter1_LC                matlab.ui.control.NumericEditField
        EditField_2Label_3              matlab.ui.control.Label
        MolecularWeight_LC              matlab.ui.control.NumericEditField
        EditFieldLabel_3                matlab.ui.control.Label
        LinkerLength2_LC                matlab.ui.control.NumericEditField
        LinkerLength1_LC                matlab.ui.control.NumericEditField
        CheckBox_3                      matlab.ui.control.CheckBox
        Panel_9                         matlab.ui.container.Panel
        GridLayout2_9                   matlab.ui.container.GridLayout
        Koff_LigandC_R3_L3              matlab.ui.control.NumericEditField
        Kon_LigandC_R3_L3               matlab.ui.control.NumericEditField
        Koff_LigandC_R2_L3              matlab.ui.control.NumericEditField
        Kon_LigandC_R2_L3               matlab.ui.control.NumericEditField
        Koff_LigandC_R1_L3              matlab.ui.control.NumericEditField
        Kon_LigandC_R1_L3               matlab.ui.control.NumericEditField
        Panel_8                         matlab.ui.container.Panel
        GridLayout2_8                   matlab.ui.container.GridLayout
        Koff_LigandC_R3_L2              matlab.ui.control.NumericEditField
        Kon_LigandC_R3_L2               matlab.ui.control.NumericEditField
        Koff_LigandC_R2_L2              matlab.ui.control.NumericEditField
        Kon_LigandC_R2_L2               matlab.ui.control.NumericEditField
        Koff_LigandC_R1_L2              matlab.ui.control.NumericEditField
        Kon_LigandC_R1_L2               matlab.ui.control.NumericEditField
        Panel_7                         matlab.ui.container.Panel
        GridLayout2_7                   matlab.ui.container.GridLayout
        Koff_LigandC_R3_L1              matlab.ui.control.NumericEditField
        Kon_LigandC_R3_L1               matlab.ui.control.NumericEditField
        Koff_LigandC_R2_L1              matlab.ui.control.NumericEditField
        Kon_LigandC_R2_L1               matlab.ui.control.NumericEditField
        Koff_LigandC_R1_L1              matlab.ui.control.NumericEditField
        Kon_LigandC_R1_L1               matlab.ui.control.NumericEditField
        Image_Tab_LigandC               matlab.ui.control.Image
        ReceptorTab                     matlab.ui.container.Tab
        ReceptorConcentration_Receptor_4  matlab.ui.control.NumericEditField
        RU_Receptor                     matlab.ui.control.NumericEditField
        MolecularWeight_Receptor        matlab.ui.control.NumericEditField
        PersistanceLength2_Receptor     matlab.ui.control.NumericEditField
        EditField_8Label_4              matlab.ui.control.Label
        PersistanceLength1_Receptor     matlab.ui.control.NumericEditField
        EditField_7Label_4              matlab.ui.control.Label
        UnitDiameter3_Receptor          matlab.ui.control.NumericEditField
        EditField_4Label_4              matlab.ui.control.Label
        UnitDiameter2_Receptor          matlab.ui.control.NumericEditField
        EditField_3Label_4              matlab.ui.control.Label
        UnitDiameter1_Receptor          matlab.ui.control.NumericEditField
        EditField_2Label_4              matlab.ui.control.Label
        LinkerLength2_Receptor          matlab.ui.control.NumericEditField
        LinkerLength1_Receptor          matlab.ui.control.NumericEditField
        Image_Tab_Receptor              matlab.ui.control.Image
        ConcentrationsTab               matlab.ui.container.Tab
        LigandC_EditField               matlab.ui.control.NumericEditField
        LigandCMEditFieldLabel          matlab.ui.control.Label
        LigandB_EditField               matlab.ui.control.NumericEditField
        LigandBMEditFieldLabel          matlab.ui.control.Label
        LigandA_EditField               matlab.ui.control.NumericEditField
        LigandAMEditFieldLabel          matlab.ui.control.Label
        TimepointsEditField             matlab.ui.control.NumericEditField
        TimepointssEditFieldLabel       matlab.ui.control.Label
        ResettimepointsButton           matlab.ui.control.Button
        AddNewTimepointButton           matlab.ui.control.Button
        Table_Timepoints                matlab.ui.control.Table
        UIAxes_Timeseries_plot          matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        ProgressPanel                   matlab.ui.container.Panel
        ProgressLabel                   matlab.ui.control.Label
        ProgressButton                  matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        OverviewTab                     matlab.ui.container.Tab
        BindingResponseunitsLabel       matlab.ui.control.Label
        Slider_main                     matlab.ui.control.Slider
        CheckBox_Main_RU                matlab.ui.control.CheckBox
        CheckBox_Main_M                 matlab.ui.control.CheckBox
        Axis_Network_main               matlab.ui.control.UIAxes
        Axis_GroupedStates_main         matlab.ui.control.UIAxes
        Axis_MassCurve_main             matlab.ui.control.UIAxes
        TotalSignalTab                  matlab.ui.container.Tab
        BindingResponseunitsLabel_2     matlab.ui.control.Label
        SaveFigureButton                matlab.ui.control.Button
        CheckBox_TotalSignal_RU         matlab.ui.control.CheckBox
        CheckBox_TotalSignal_M          matlab.ui.control.CheckBox
        Axis_MassCurve_separate         matlab.ui.control.UIAxes
        MicrostatesTab                  matlab.ui.container.Tab
        BindingResponseunitsLabel_3     matlab.ui.control.Label
        SaveFigureButton_2              matlab.ui.control.Button
        CheckBox_MicroStates_RU         matlab.ui.control.CheckBox
        CheckBox_MicroStates_M          matlab.ui.control.CheckBox
        Axis_SignalComponents_separate  matlab.ui.control.UIAxes
        GroupedbyClassTab               matlab.ui.container.Tab
        BindingResponseunitsLabel_4     matlab.ui.control.Label
        ClassesDropDown                 matlab.ui.control.DropDown
        ClassesDropDownLabel            matlab.ui.control.Label
        SaveFigureButton_3              matlab.ui.control.Button
        CheckBox_Grouped_RU             matlab.ui.control.CheckBox
        CheckBox_Grouped_M              matlab.ui.control.CheckBox
        Axis_GroupedStates_separate     matlab.ui.control.UIAxes
        NetworkDynamicsTab              matlab.ui.container.Tab
        BindingResponseunitsLabel_5     matlab.ui.control.Label
        Slider_separate                 matlab.ui.control.Slider
        TimesLabel                      matlab.ui.control.Label
        ClassesDropDown_2               matlab.ui.control.DropDown
        ClassesDropDown_2Label          matlab.ui.control.Label
        CheckBox_Netw_RU                matlab.ui.control.CheckBox
        CheckBox_Netw_M                 matlab.ui.control.CheckBox
        Axis_Network_separate           matlab.ui.control.UIAxes
        EffectiveConcentrationTab       matlab.ui.container.Tab
        AllinformationCheckBox          matlab.ui.control.CheckBox
        UpdateTableButton               matlab.ui.control.Button
        EffectiveConcentrationsLabel    matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        PDFTab                          matlab.ui.container.Tab
        ReceptorPDF_Update              matlab.ui.control.Button
        LigandPDF_Update                matlab.ui.control.Button
        PDF_Cumulative                  matlab.ui.control.CheckBox
        ReceptorLabel                   matlab.ui.control.Label
        ReceptorPDF_step                matlab.ui.control.DropDown
        ReceptorPDF_values_input        matlab.ui.control.TextArea
        ProbabiltyLabel_2               matlab.ui.control.Label
        ReceptorPDF_Distance_input      matlab.ui.control.TextArea
        DistanceALabel_2                matlab.ui.control.Label
        LigandPDF_Values_input          matlab.ui.control.TextArea
        ProbabiltyLabel                 matlab.ui.control.Label
        LigandPDF_step                  matlab.ui.control.DropDown
        LigandPDF_rank                  matlab.ui.control.DropDown
        LigandDropDownLabel             matlab.ui.control.Label
        LigandPDF_Distance_input        matlab.ui.control.TextArea
        DistanceALabel                  matlab.ui.control.Label
        PDF_plot                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

 properties (Access = private)
        Output_concentraion %       The output struct of the concentration for plotting
        RU_OR_CONC %                Unit type of plotting
        initiated_plotdata  %       Whether it is the first time running the plotting or not
        export_info                
        rerunable %                 Wheter there were new inputs requireing running effC calculation or not
        PDF_updated %               Wheter there were new PDF inputs or not
        error_text
        
    end
    
    properties (Access = public)
        Parameters  %Parameters %Tha main structure of all used parameters
        TimePoints  %The timepoint matrix for ligand concentrations
    end
    
    methods (Access = private)
        % Initiate Parameter values
        function Initiate_Patameters(app)
            %% Declatration of the variables in the initial steps of the GUI
            app.Parameters = struct();
            app.Parameters.Main = struct("Ligand_number", 1);


            app.Parameters.base = struct("HomogenousLigand",false,"PlotType","basic","DefoultSetup","None", "diffs", @dif2x2, "Kon",100000, "Koff", 1, "resolution",1);
            app.Parameters.constants = struct("Area", 1.6e-6, "Volume", 1.6e-6*1e-7 ); % Double Check!!!
                app.Parameters.constants.RU2g = 1e-12*(app.Parameters.constants.Area * 1e6);
            app.Parameters.Ligand(1) = struct("Valency",2,"MolecularWeight",1,"LinkerLength",10,"LinkerLP",1,"Diameter",10); 
            app.Parameters.Ligand(2) = struct("Valency",0,"MolecularWeight",1,"LinkerLength",10,"LinkerLP",1,"Diameter",10); 
            app.Parameters.Ligand(3) = struct("Valency",0,"MolecularWeight",1,"LinkerLength",10,"LinkerLP",1,"Diameter",10); 
            app.Parameters.Receptor = struct("Valency",2,"MolecularWeight",1,"LinkerLength",10,"LinkerLP",1,"Diameter",10);
            app.Parameters.SPR = struct("AssociationTime",1,"DissociationTime",1,"FlowRate",50,"MTL_modifyer",0.1,"baseRU",24,"L0",1e-6);
            app.Parameters.SPR.flowMTL = (app.Parameters.SPR.FlowRate/10)/100;% Double Check!!! % Rerun!!
            app.Parameters.SPR.MTL = 0.98*((10^(-0.434*log10(app.Parameters.Ligand(1).MolecularWeight)-4.059)*10^-4)/(100*10^-9))^(2/3)*(app.Parameters.SPR.flowMTL/(0.2963*1.6*10^-6))^(1/3);% Double Check!!! % Rerun!!
            app.Parameters.SPR.netMTL = app.Parameters.SPR.MTL / app.Parameters.SPR.MTL_modifyer;% Double Check!!! % Rerun!!   
            app.Parameters.SPR.baseR0 = ((app.Parameters.SPR.baseRU * app.Parameters.constants.RU2g )/ app.Parameters.Receptor(1).MolecularWeight )/(app.Parameters.constants.Volume * 1000); % Rerun!!
            %app.Parameters.SPR.RUmod= ((app.Parameters.constants.RU2g )/ app.Parameters.Ligand(1).MolecularWeight )/(app.Parameters.constants.Volume * 1000);
            app.Parameters.Plotting = struct("PlotClass","Ligand", "NetworkPlotClass", "Valency");
            app.Parameters.PDF_input = struct("Cumulative", false, "Ligand_rank", 1, "Ligand_from", 1, "Ligand_to", 2, "Receptor_from", 1, "Receptor_to" ,2, "Receptor_x", [], "Receptor_y", [], "Ligand_x", [], "Ligand_y", []);
            
            app.TimePoints = array2table([150, 5e-10, 5e-9, 5e-8;500, 0, 0, 0]);
            app.Table_Timepoints.Data = app.TimePoints;
            app.RU_OR_CONC = "RU";
            app.initiated_plotdata = 0;
            app.export_info = 0;
            app.rerunable = 0;
            app.PDF_updated = 0;
            app.error_text = [];
            
        end
        
        %2) Set receptor valency based on the image selection    
        function setReceptor(app)
            %1) Receptor
            app.rerunable = 0;
            app.RerunButton.Enable = false;
            switch app.Parameters.Receptor.Valency
                case 1
                    %Receptor_1 is fixed
                        app.Receptor_linker_1.ImageSource = 'Empty.png';
                    app.iButton_Receptor_2.ImageSource = 'Empty.png';
                        app.Receptor_linker_2.ImageSource = 'Empty.png';
                    app.iButton_Receptor_3.ImageSource = 'Empty.png';
                                        
                case 2 
                    %Receptor_1 is fixed
                        app.Receptor_linker_1.ImageSource = 'Linker_receptor.png';
                    app.iButton_Receptor_2.ImageSource = 'Receptor.png';
                        app.Receptor_linker_2.ImageSource = 'Empty.png';
                    app.iButton_Receptor_3.ImageSource = 'Empty.png';
                        
                case 3
                    %Receptor_1 is fixed
                        app.Receptor_linker_1.ImageSource = 'Linker_receptor.png';
                    app.iButton_Receptor_2.ImageSource = 'Receptor.png';
                        app.Receptor_linker_2.ImageSource = 'Linker_receptor.png';
                    app.iButton_Receptor_3.ImageSource = 'Receptor.png';
                    
                    
            end
            %2) Ligand A
            switch app.Parameters.Ligand(1).Valency
                case 0    
                    app.TitleImage_LigandA.ImageSource = 'Empty.png';
                    app.iButton_LigandA_1.ImageSource = 'Empty.png';
                        app.Image_linker_LigandA_1.ImageSource = 'Empty.png';
                    app.iButton_LigandA_2.ImageSource = 'Empty.png';
                        app.Image_linker_LigandA_2.ImageSource = 'Empty.png';
                    app.iButton_LigandA_3.ImageSource = 'Empty.png';
                case 1
                    app.TitleImage_LigandA.ImageSource = 'Ligand_A_0.png';
                    app.iButton_LigandA_1.ImageSource = 'Ligand_A_1.png';
                        app.Image_linker_LigandA_1.ImageSource = 'Empty.png';
                    app.iButton_LigandA_2.ImageSource = 'Empty.png';
                        app.Image_linker_LigandA_2.ImageSource = 'Empty.png';
                    app.iButton_LigandA_3.ImageSource = 'Empty.png';
                case 2 
                    app.TitleImage_LigandA.ImageSource = 'Ligand_A_0.png';
                    app.iButton_LigandA_1.ImageSource = 'Ligand_A_1.png';
                        app.Image_linker_LigandA_1.ImageSource = 'Linker_ligand.png';
                    app.iButton_LigandA_2.ImageSource = 'Ligand_A_1.png';
                        app.Image_linker_LigandA_2.ImageSource = 'Empty.png';
                    app.iButton_LigandA_3.ImageSource = 'Empty.png';
                case 3
                    app.TitleImage_LigandA.ImageSource = 'Ligand_A_0.png';
                    app.iButton_LigandA_1.ImageSource = 'Ligand_A_1.png';
                        app.Image_linker_LigandA_1.ImageSource = 'Linker_ligand.png';
                    app.iButton_LigandA_2.ImageSource = 'Ligand_A_1.png';
                        app.Image_linker_LigandA_2.ImageSource = 'Linker_ligand.png';
                    app.iButton_LigandA_3.ImageSource = 'Ligand_A_1.png';
            end
            %3) Ligand B
            switch app.Parameters.Ligand(2).Valency
                case 0    
                    app.TitleImage_LigandB.ImageSource = 'Empty.png';
                    app.iButton_LigandB_1.ImageSource = 'Empty.png';
                        app.Image_linker_LigandB_1.ImageSource = 'Empty.png';
                    app.iButton_LigandB_2.ImageSource = 'Empty.png';
                        app.Image_linker_LigandB_2.ImageSource = 'Empty.png';
                    app.iButton_LigandB_3.ImageSource = 'Empty.png';
                case 1
                    app.TitleImage_LigandB.ImageSource = 'Ligand_B_0.png';
                    app.iButton_LigandB_1.ImageSource = 'Ligand_B_1.png';
                        app.Image_linker_LigandB_1.ImageSource = 'Empty.png';
                    app.iButton_LigandB_2.ImageSource = 'Empty.png';
                        app.Image_linker_LigandB_2.ImageSource = 'Empty.png';
                    app.iButton_LigandB_3.ImageSource = 'Empty.png';
                case 2 
                    app.TitleImage_LigandB.ImageSource = 'Ligand_B_0.png';
                    app.iButton_LigandB_1.ImageSource = 'Ligand_B_1.png';
                        app.Image_linker_LigandB_1.ImageSource = 'Linker_ligand.png';
                    app.iButton_LigandB_2.ImageSource = 'Ligand_B_1.png';
                        app.Image_linker_LigandB_2.ImageSource = 'Empty.png';
                    app.iButton_LigandB_3.ImageSource = 'Empty.png';
                case 3
                    app.TitleImage_LigandB.ImageSource = 'Ligand_B_0.png';
                    app.iButton_LigandB_1.ImageSource = 'Ligand_B_1.png';
                        app.Image_linker_LigandB_1.ImageSource = 'Linker_ligand.png';
                    app.iButton_LigandB_2.ImageSource = 'Ligand_B_1.png';
                        app.Image_linker_LigandB_2.ImageSource = 'Linker_ligand.png';
                    app.iButton_LigandB_3.ImageSource = 'Ligand_B_1.png';
            end
            %4) Ligand C
            switch app.Parameters.Ligand(3).Valency
                case 0    
                    app.TitleImage_LigandC.ImageSource = 'Empty.png';
                    app.iButton_LigandC_1.ImageSource = 'Empty.png';
                        app.Image_linker_LigandC_1.ImageSource = 'Empty.png';
                    app.iButton_LigandC_2.ImageSource = 'Empty.png';
                        app.Image_linker_LigandC_2.ImageSource = 'Empty.png';
                    app.iButton_LigandC_3.ImageSource = 'Empty.png';
                case 1
                    app.TitleImage_LigandC.ImageSource = 'Ligand_C_0.png';
                    app.iButton_LigandC_1.ImageSource = 'Ligand_C_1.png';
                        app.Image_linker_LigandC_1.ImageSource = 'Empty.png';
                    app.iButton_LigandC_2.ImageSource = 'Empty.png';
                        app.Image_linker_LigandC_2.ImageSource = 'Empty.png';
                    app.iButton_LigandC_3.ImageSource = 'Empty.png';
                case 2 
                    app.TitleImage_LigandC.ImageSource = 'Ligand_C_0.png';
                    app.iButton_LigandC_1.ImageSource = 'Ligand_C_1.png';
                        app.Image_linker_LigandC_1.ImageSource = 'Linker_ligand.png';
                    app.iButton_LigandC_2.ImageSource = 'Ligand_C_1.png';
                        app.Image_linker_LigandC_2.ImageSource = 'Empty.png';
                    app.iButton_LigandC_3.ImageSource = 'Empty.png';
                case 3
                    app.TitleImage_LigandC.ImageSource = 'Ligand_C_0.png';
                    app.iButton_LigandC_1.ImageSource = 'Ligand_C_1.png';
                        app.Image_linker_LigandC_1.ImageSource = 'Linker_ligand.png';
                    app.iButton_LigandC_2.ImageSource = 'Ligand_C_1.png';
                        app.Image_linker_LigandC_2.ImageSource = 'Linker_ligand.png';
                    app.iButton_LigandC_3.ImageSource = 'Ligand_C_1.png';
            end
            
            % Pictures used to display behind the input fields determined
            % by the set options
            app.Image_Tab_LigandA.ImageSource = strcat('MK_Tab2_','A', ...
                num2str(app.Parameters.Ligand(1).Valency),'x', ...
                num2str(app.Parameters.Receptor.Valency),'.png' );
            app.Image_Tab_LigandB.ImageSource = strcat('MK_Tab2_','B', ...
                num2str(app.Parameters.Ligand(2).Valency),'x', ...
                num2str(app.Parameters.Receptor.Valency),'.png' );
            app.Image_Tab_LigandC.ImageSource = strcat('MK_Tab2_','C', ...
                num2str(app.Parameters.Ligand(3).Valency),'x', ...
                num2str(app.Parameters.Receptor.Valency),'.png' );
            app.Image_Tab_Receptor.ImageSource = strcat('GUI Rec_Tab_R', ...
                num2str(app.Parameters.Receptor.Valency),'.png' );
            
            app.Parameters.base.diffs = str2func(strcat("@Diff_",num2str(app.Parameters.Receptor.Valency),"R",...
                num2str(app.Parameters.Ligand(1).Valency),"LA_",...
                num2str(app.Parameters.Ligand(2).Valency),"LB_",...
                num2str(app.Parameters.Ligand(3).Valency),"LC"));
            
            LigandCHR = ["A", "B", "C"];
            for ligand = 1:3
                for lig_val = 1:3
                    for rec_val = 1:3
                        Konfield = strcat("Kon_Ligand",LigandCHR(ligand),"_R",num2str(rec_val),"_L", num2str(lig_val));
                        Koffield = strcat("Koff_Ligand",LigandCHR(ligand),"_R",num2str(rec_val),"_L", num2str(lig_val));
                        Diameterfield = strcat("UnitDiameter",num2str(lig_val),"_L",LigandCHR(ligand));
                        LinkerLengthfield = strcat("LinkerLength",num2str(lig_val-1),"_L",LigandCHR(ligand));
                        LinkerLPfield = strcat("PersistanceLength",num2str(lig_val-1),"_L",LigandCHR(ligand));
                        if app.Parameters.Ligand(ligand).Valency < lig_val
                            app.(Konfield).Enable = false;
                            app.(Koffield).Enable = false;
                            app.(Diameterfield).Enable = false;                            
                            if lig_val > 1
                            app.(LinkerLengthfield).Enable = false;
                            app.(LinkerLPfield).Enable = false;
                            end
                        else
                            if  app.Parameters.Receptor.Valency < rec_val
                                app.(Konfield).Enable = false;
                                app.(Koffield).Enable = false;                              
                            else
                                app.(Konfield).Enable = true;
                                app.(Koffield).Enable = true;                                
                            end
                            app.(Diameterfield).Enable = true;
                            if lig_val > 1
                            app.(LinkerLengthfield).Enable = true;
                            app.(LinkerLPfield).Enable = true;
                            end
                        end
                    end
                end
            end


        end


        function copy_all_parameters_from_default(app)
            %% This step is required to run the GUI without errors
            app.Kon_LigandA_R1_L1ValueChanged;
            app.Kon_LigandA_R2_L1ValueChanged;
            app.Kon_LigandA_R3_L1ValueChanged;
            app.Kon_LigandA_R1_L2ValueChanged;
            app.Kon_LigandA_R2_L2ValueChanged;
            app.Kon_LigandA_R3_L2ValueChanged;
            app.Kon_LigandA_R1_L3ValueChanged;
            app.Kon_LigandA_R2_L3ValueChanged;
            app.Kon_LigandA_R3_L3ValueChanged;
            app.Koff_LigandA_R1_L1ValueChanged;
            app.Koff_LigandA_R2_L1ValueChanged;
            app.Koff_LigandA_R3_L1ValueChanged;
            app.Koff_LigandA_R1_L2ValueChanged;
            app.Koff_LigandA_R2_L2ValueChanged;
            app.Koff_LigandA_R3_L2ValueChanged;
            app.Koff_LigandA_R1_L3ValueChanged;
            app.Koff_LigandA_R2_L3ValueChanged;
            app.Koff_LigandA_R3_L3ValueChanged;
            app.MolecularWeight_LAValueChanged;
            app.UnitDiameter1_LAValueChanged;
            app.UnitDiameter2_LAValueChanged;
            app.UnitDiameter3_LAValueChanged;
            app.LinkerLength1_LAValueChanged;
            app.LinkerLength2_LAValueChanged;
            app.PersistanceLength1_LAValueChanged;
            app.PersistanceLength2_LAValueChanged;
            app.MolecularWeight_LBValueChanged;
            app.Kon_LigandB_R1_L1ValueChanged;
            app.Kon_LigandB_R2_L1ValueChanged;
            app.Kon_LigandB_R3_L1ValueChanged;
            app.Kon_LigandB_R1_L2ValueChanged;
            app.Kon_LigandB_R2_L2ValueChanged;
            app.Kon_LigandB_R3_L2ValueChanged;
            app.Kon_LigandB_R1_L3ValueChanged;
            app.Kon_LigandB_R2_L3ValueChanged;
            app.Kon_LigandB_R3_L3ValueChanged;
            app.UnitDiameter1_LCValueChanged;
            app.UnitDiameter2_LCValueChanged;
            app.UnitDiameter3_LCValueChanged;
            app.LinkerLength1_LCValueChanged;
            app.LinkerLength2_LCValueChanged;
            app.PersistanceLength1_LCValueChanged;
            app.PersistanceLength2_LCValueChanged;
            app.LinkerLength1_LBValueChanged;
            app.LinkerLength2_LBValueChanged;
            app.UnitDiameter1_LBValueChanged;
            app.UnitDiameter2_LBValueChanged;
            app.UnitDiameter3_LBValueChanged;
            app.PersistanceLength1_LBValueChanged;
            app.PersistanceLength2_LBValueChanged;
            app.Kon_LigandC_R1_L1ValueChanged;
            app.Kon_LigandC_R2_L1ValueChanged;
            app.Kon_LigandC_R3_L1ValueChanged;
            app.Kon_LigandC_R1_L2ValueChanged;
            app.Kon_LigandC_R2_L2ValueChanged;
            app.Kon_LigandC_R3_L2ValueChanged;
            app.Kon_LigandC_R1_L3ValueChanged;
            app.Kon_LigandC_R2_L3ValueChanged;
            app.Kon_LigandC_R3_L3ValueChanged;
            app.MolecularWeight_ReceptorValueChanged;
            app.UnitDiameter1_ReceptorValueChanged;
            app.UnitDiameter2_ReceptorValueChanged;
            app.UnitDiameter3_ReceptorValueChanged;
            app.LinkerLength1_ReceptorValueChanged;
            app.LinkerLength2_ReceptorValueChanged;
            app.PersistanceLength1_ReceptorValueChanged;
            app.PersistanceLength2_ReceptorValueChanged;
            app.MolecularWeight_LCValueChanged;
            app.RU_ReceptorValueChanged;
            app.Koff_LigandB_R1_L1ValueChanged;
            app.Koff_LigandB_R2_L1ValueChanged;
            app.Koff_LigandB_R3_L1ValueChanged;
            app.Koff_LigandB_R1_L2ValueChanged;
            app.Koff_LigandB_R2_L2ValueChanged;
            app.Koff_LigandB_R3_L2ValueChanged;
            app.Koff_LigandB_R1_L3ValueChanged;
            app.Koff_LigandB_R2_L3ValueChanged;
            app.Koff_LigandB_R3_L3ValueChanged;
            app.Koff_LigandC_R1_L1ValueChanged;
            app.Koff_LigandC_R2_L1ValueChanged;
            app.Koff_LigandC_R3_L1ValueChanged;
            app.Koff_LigandC_R1_L2ValueChanged;
            app.Koff_LigandC_R2_L2ValueChanged;
            app.Koff_LigandC_R3_L2ValueChanged;
            app.Koff_LigandC_R1_L3ValueChanged;
            app.Koff_LigandC_R2_L3ValueChanged;
            app.Koff_LigandC_R3_L3ValueChanged;
            
            app.ClassesDropDown.ValueChangedFcn;
            app.ClassesDropDown_2.ValueChangedFcn;
         
        end
        
        function Checkbox_flip(app, RUorCONC)
           %% To change parameters according to output types (RU or concentration)
           app.RU_OR_CONC = RUorCONC;
           if RUorCONC == "CONC"
               app.CheckBox_Main_M.Value = 1;
               app.CheckBox_Main_RU.Value = 0;
               app.CheckBox_MicroStates_M.Value = 1;
               app.CheckBox_MicroStates_RU.Value = 0;
               app.CheckBox_Grouped_M.Value = 1;
               app.CheckBox_Grouped_RU.Value = 0;
               app.CheckBox_Netw_M.Value = 1;
               app.CheckBox_Netw_RU.Value = 0;
               app.CheckBox_TotalSignal_M.Value = 1;
               app.CheckBox_TotalSignal_RU.Value = 0;
               
           elseif RUorCONC == "RU"
               app.CheckBox_Main_M.Value = 0;
               app.CheckBox_Main_RU.Value = 1;
               app.CheckBox_MicroStates_M.Value = 0;
               app.CheckBox_MicroStates_RU.Value = 1;
               app.CheckBox_Grouped_M.Value = 0;
               app.CheckBox_Grouped_RU.Value = 1;
               app.CheckBox_Netw_M.Value = 0;
               app.CheckBox_Netw_RU.Value = 1;
               app.CheckBox_TotalSignal_M.Value = 0;
               app.CheckBox_TotalSignal_RU.Value = 1;
            end
            
        end
        
        function WebAppPlot(app)
            %% Main plotting function.
            %  Displays all outputs on the different output fields
            if size(app.Output_concentraion.States.time,1) > 2
                delete(app.error_text);
                if app.initiated_plotdata == 1                
                if app.RU_OR_CONC == "RU"             
                     plot(app.Axis_MassCurve_main, app.Output_concentraion.States.time, sum(app.Output_concentraion.States.RU, 2), 'Color', [0,0,0], 'LineWidth',2);
                     hold(app.Axis_MassCurve_main, "on")
                     plot(app.Axis_MassCurve_main, app.Output_concentraion.States.time, app.Output_concentraion.States.RU);
                     hold(app.Axis_MassCurve_main, "off")
                     app.Axis_MassCurve_main.YLabel.String = "Binding Response";
        
                     plot(app.Axis_MassCurve_separate, app.Output_concentraion.States.time, sum(app.Output_concentraion.States.RU, 2));
                     app.Axis_MassCurve_separate.YLabel.String = "Binding Response";
                     plot(app.Axis_SignalComponents_separate, app.Output_concentraion.States.time, app.Output_concentraion.States.RU);
                     if size(app.Output_concentraion.States.names,2) < 30
                        legend(app.Axis_SignalComponents_separate, app.Output_concentraion.States.names)
                     end                     
                     %legend(app.Axis_SignalComponents_separate, ["1","2","1","1","1","1","1","1","1","10","11",])
                     %legend(app.Axis_SignalComponents_separate,'off')
                     app.Axis_SignalComponents_separate.YLabel.String = "Binding Response";
                 
                elseif app.RU_OR_CONC == "CONC"
                    plot(app.Axis_MassCurve_main, app.Output_concentraion.States.time, sum(app.Output_concentraion.States.conc, 2), 'Color', [0,0,0], 'LineWidth',2);
                     hold(app.Axis_MassCurve_main, "on")
                     plot(app.Axis_MassCurve_main, app.Output_concentraion.States.time, app.Output_concentraion.States.conc);
                     hold(app.Axis_MassCurve_main, "off")
                     app.Axis_MassCurve_main.YLabel.String = "Molar";
                    
                     plot(app.Axis_MassCurve_main, app.Output_concentraion.States.time, sum(app.Output_concentraion.States.conc, 2));
                     plot(app.Axis_MassCurve_separate, app.Output_concentraion.States.time, sum(app.Output_concentraion.States.conc, 2));
                     app.Axis_MassCurve_separate.YLabel.String = "Molar";
                     plot(app.Axis_SignalComponents_separate, app.Output_concentraion.States.time, app.Output_concentraion.States.conc);
                     if size(app.Output_concentraion.States.names,2) < 30
                        legend(app.Axis_SignalComponents_separate, app.Output_concentraion.States.names)
                     end                      
                     app.Axis_SignalComponents_separate.YLabel.String = "Molar";
                end  
                
                 app.graph_plot;
                 app.Slider_main.Limits = [0,app.Output_concentraion.States.time(end)];
                 app.Slider_main.MinorTicks = app.Output_concentraion.States.time;
                 app.Slider_separate.Limits = [0,app.Output_concentraion.States.time(end)];
                 app.Slider_separate.MinorTicks = app.Output_concentraion.States.time;
                 app.group_plot;
                 app.Slider_separateValueChanged;
                 app.Slider_mainValueChanged;
                end
            else
                app.error_text = text(app.Axis_MassCurve_main,0.3,0.5, 'Choosen parameters lead to\newlineintegration error', "FontSize", 30);
            end
        end
        
        function group_plot(app)
            %% Plotting funciton for the grouped states
            if app.initiated_plotdata == 1
            
                
                if app.Parameters.Plotting.PlotClass == "Ligand"
                    legend_text = [];
                    map = containers.Map([0, 1, 2, 3, 12, 13, 23, 123],...
                        ["-", "A","B","C","AB","AC","BC","ABC"]);
                    for i = app.Output_concentraion.Ligand_Classes.class_names
                        legend_text = [legend_text, string(map(i))];
                    end
                elseif app.Parameters.Plotting.PlotClass == "Valency"
                    legend_text = [];
                    map = containers.Map([0, 1, 11, 111, 2, 21, 3],...
                        ["0","1","11","111","2","21","3"]);
                    for i = app.Output_concentraion.Valency_Classes.class_names
                        legend_text = [legend_text, string(map(i))];
                    end
                elseif app.Parameters.Plotting.PlotClass == "Mixed"
                    legend_text = [];
                    map = containers.Map([0, 31, 32, 33, 2111, 2112, 2113, 2211,2212, 2213, 2311, 2312, 2313, 21, 22, 23, 111213, 1112, 1113, 1213, 11, 12, 13 ],...
                        ["0", "3A","3B","3C","2AA","2AB","2AC","2BA","2BB","2BC","2CA","2CB","2CC","2A", "2B", "2C", "ABC", "AB", "AC", "BC", "A", "B", "C"]);
                    for i = app.Output_concentraion.Mixed_Classes.class_names
                        legend_text = [legend_text, string(map(i))];
                    end
                end                
                
            if app.RU_OR_CONC == "RU"       
                if app.Parameters.Plotting.PlotClass == "Ligand"
                  plot(app.Axis_GroupedStates_main, app.Output_concentraion.States.time, app.Output_concentraion.Ligand_Classes.RU(:,2:end))
                  legend(app.Axis_GroupedStates_main, legend_text(2:end))
                  plot(app.Axis_GroupedStates_separate, app.Output_concentraion.States.time, app.Output_concentraion.Ligand_Classes.RU(:,2:end))
                  legend(app.Axis_GroupedStates_separate, legend_text(2:end))
                elseif app.Parameters.Plotting.PlotClass == "Valency"
                  plot(app.Axis_GroupedStates_main, app.Output_concentraion.States.time, app.Output_concentraion.Valency_Classes.RU(:,2:end))
                  legend(app.Axis_GroupedStates_main, legend_text(2:end))  
                  plot(app.Axis_GroupedStates_separate, app.Output_concentraion.States.time, app.Output_concentraion.Valency_Classes.RU(:,2:end))
                  legend(app.Axis_GroupedStates_separate, legend_text(2:end)) 
                elseif app.Parameters.Plotting.PlotClass == "Mixed"
                  plot(app.Axis_GroupedStates_main, app.Output_concentraion.States.time, app.Output_concentraion.Mixed_Classes.RU(:,2:end))
                  legend(app.Axis_GroupedStates_main, legend_text(2:end))  
                  plot(app.Axis_GroupedStates_separate, app.Output_concentraion.States.time, app.Output_concentraion.Mixed_Classes.RU(:,2:end))
                  legend(app.Axis_GroupedStates_separate, legend_text(2:end))                   
                end
            elseif app.RU_OR_CONC == "CONC"
                if app.Parameters.Plotting.PlotClass == "Ligand"
                  plot(app.Axis_GroupedStates_main, app.Output_concentraion.States.time, app.Output_concentraion.Ligand_Classes.conc(:,2:end))
                  legend(app.Axis_GroupedStates_main, legend_text(2:end))
                  plot(app.Axis_GroupedStates_separate, app.Output_concentraion.States.time, app.Output_concentraion.Ligand_Classes.conc(:,2:end))
                  legend(app.Axis_GroupedStates_separate, legend_text(2:end))
                elseif app.Parameters.Plotting.PlotClass == "Valency"
                  plot(app.Axis_GroupedStates_main, app.Output_concentraion.States.time, app.Output_concentraion.Valency_Classes.conc(:,2:end))
                  legend(app.Axis_GroupedStates_main, legend_text(2:end))  
                  plot(app.Axis_GroupedStates_separate, app.Output_concentraion.States.time, app.Output_concentraion.Valency_Classes.conc(:,2:end))
                  legend(app.Axis_GroupedStates_separate, legend_text(2:end)) 
                elseif app.Parameters.Plotting.PlotClass == "Mixed"
                  plot(app.Axis_GroupedStates_main, app.Output_concentraion.States.time, app.Output_concentraion.Mixed_Classes.conc(:,2:end))
                  legend(app.Axis_GroupedStates_main, legend_text(2:end))  
                  plot(app.Axis_GroupedStates_separate, app.Output_concentraion.States.time, app.Output_concentraion.Mixed_Classes.conc(:,2:end))
                  legend(app.Axis_GroupedStates_separate, legend_text(2:end)) 
                  
                end
            end  
            end
        
        end
        
        function  graph_plot(app)
            %% Plotting function for the network representation of the states
            if app.Parameters.Plotting.NetworkPlotClass == "Valency"
                ntw = [[0,1,0,0,0,0,0];
                    [1,0,1,0,1,0,0];
                    [0,1,0,1,0,1,0];
                    [0,0,1,0,0,0,0];
                    [0,1,0,0,0,1,1];
                    [0,0,1,0,1,0,0];
                    [0,0,0,0,1,0,0]];

                ntw = graph(ntw, string([0,1,2,3,11,21,111])); 
                nodes = find(ismember( string(table2array(ntw.Nodes)), string(unique(app.Output_concentraion.Valency_Classes.class_names)))');
                ntw = ntw.rmnode(find(~ismember(1:7, nodes)));

                xpos = [2,2,2,2,5,5,5];
                ypos = [10,8,6,4,8,6,4];
             elseif app.Parameters.Plotting.NetworkPlotClass == "Ligand"
                ntw = [[0,0,0,1,1,0,0];
                    [0,0,0,1,0,1,0];
                    [0,0,0,0,1,1,0];
                    [1,1,0,0,0,0,1];
                    [1,0,1,0,0,0,1];
                    [0,1,1,0,0,0,1];
                    [0,0,0,1,1,1,0]];


                ntw = graph(ntw, string(["A","B","C","AB","AC","BC","ABC"])); 
                nodes = find(ismember( string(["1", "2", "3", "12", "13", "23", "123"]), string(unique(app.Output_concentraion.Ligand_Classes.class_names)))');
                ntw = ntw.rmnode(find(~ismember(1:7, nodes)));
           
                xpos = [2,2,2,5,5,5,8];
                ypos = [10,6,2,8,6,4,6];
             end

            
            plot(app.Axis_Network_main, ntw, 'XData', xpos(nodes), 'YData', ypos(nodes), 'MarkerSize', 20)
            colormap(app.Axis_Network_main, 'jet');
            cb = colorbar(app.Axis_Network_main);
            cb.TickLabels = [{'10^{-6}'; '10^{-5}';'10^{-4}';'0.001';'0.01';'0.1';'10';'100';}]; %#ok<NBRAK> 
            cb.Ticks = 0:(1/6):1;
            
            plot(app.Axis_Network_separate, ntw, 'XData', xpos(nodes), 'YData', ypos(nodes), 'MarkerSize', 50)
            colormap(app.Axis_Network_separate, 'jet');
            cb2 = colorbar(app.Axis_Network_separate);
            cb2.TickLabels = [{'10^{-6}'; '10^{-5}';'10^{-4}';'0.001';'0.01';'0.1';'10';'100';}]; %#ok<NBRAK> 
            cb2.Ticks = 0:(1/6):1;
        end
        
        
        function PDF_plot_curves(app, to_update, update)
            %% Plotting function for the PDFs
            if app.Parameters.Ligand(app.Parameters.PDF_input.Ligand_rank).Valency < 2 ...
                    || app.Parameters.Receptor(1).Valency < 2 ...
                    || str2num(app.LigandPDF_step.Value) < 2 ...
                    || str2num(app.ReceptorPDF_step.Value) < 2
                cla(app.PDF_plot)
                return                
            end
            
                app.Parameters.PDF_input.Cumulative
                receptor_i = 1;
                if length(app.Parameters.Receptor(receptor_i).binding_state) == 1
                    receptor_binding_state = 1;
                    receptor_x = app.Parameters.Receptor(receptor_i).binding_state(1).PDF_x;
                    receptor_y = app.Parameters.Receptor(receptor_i).binding_state(1).PDF_y;
                else
                    Receptor_table = struct2table( app.Parameters.Receptor(receptor_i).binding_state);
                    receptor_position_array = table2array(Receptor_table(:,[2,3]));
                    receptor_position_from = app.Parameters.PDF_input.Receptor_from;
                    receptor_position_to = app.Parameters.PDF_input.Receptor_to;
                    receptor_binding_state = find(receptor_position_array(:,1)==receptor_position_from & receptor_position_array(:,2)==receptor_position_to);
                    receptor_x = Receptor_table.PDF_x(receptor_binding_state,:);
                    receptor_y=  Receptor_table.PDF_y(receptor_binding_state,:);
                end
                
                ligand_i = app.Parameters.PDF_input.Ligand_rank;
                if length(app.Parameters.Ligand(ligand_i).binding_state) == 1
                    ligand_binding_state = 1;
                    ligand_x = app.Parameters.Ligand(ligand_i).binding_state(1).PDF_x;
                    ligand_y = app.Parameters.Ligand(ligand_i).binding_state(1).PDF_y;
                else
                    Ligand_table = struct2table( app.Parameters.Ligand(ligand_i).binding_state);
                    ligand_position_array = table2array(Ligand_table(:,[2,3]));
                    ligand_position_from = app.Parameters.PDF_input.Ligand_from;
                    ligand_position_to = app.Parameters.PDF_input.Ligand_to;
                    ligand_binding_state = find(ligand_position_array(:,1)==ligand_position_from & ligand_position_array(:,2)==ligand_position_to);
                    ligand_x = Ligand_table.PDF_x(ligand_binding_state,:);
                    ligand_y=  Ligand_table.PDF_y(ligand_binding_state,:);
                end
                
                cla(app.PDF_plot)
                hold(app.PDF_plot, "on")
                
                if app.Parameters.PDF_input.Cumulative == 1
                    receptor_y = receptor_y.*receptor_x.^2;
                    ligand_y   = ligand_y.*ligand_x.^2;
                end
                receptor_y = app.PDF_normalise(receptor_y,receptor_x);
                plot(app.PDF_plot, receptor_x(1:end-1),receptor_y(1:end-1))
                ligand_y = app.PDF_normalise(ligand_y, ligand_x);
                plot(app.PDF_plot, ligand_x(1:end-1),ligand_y(1:end-1))
                legend(app.PDF_plot, ["Receptor","Ligand"])


            if update == 1
                if to_update == "Receptor"
                    plot(app.PDF_plot, app.Parameters.PDF_input.Receptor_x(1:end-1), app.Parameters.PDF_input.Receptor_y(1:end-1))
                    legend(app.PDF_plot, ["Old Receptor","Ligand","New Receptor"])
                    
                    app.Parameters.Receptor(receptor_i).binding_state(receptor_binding_state).user_input = 1;        
                    app.Parameters.Receptor(receptor_i).binding_state(receptor_binding_state).PDF_x = app.Parameters.PDF_input.Receptor_x;
                    app.Parameters.Receptor(receptor_i).binding_state(receptor_binding_state).PDF_y = app.Parameters.PDF_input.Receptor_y;

                elseif to_update == "Ligand"
                    plot(app.PDF_plot, app.Parameters.PDF_input.Ligand_x(1:end-1), app.Parameters.PDF_input.Ligand_y(1:end-1))
                    legend(app.PDF_plot, ["Receptor","Old Ligand","New Ligand"])
                    
                    app.Parameters.Ligand(ligand_i).binding_state(ligand_binding_state).user_input = 1;        
                    app.Parameters.Ligand(ligand_i).binding_state(ligand_binding_state).PDF_x = app.Parameters.PDF_input.Ligand_x;
                    app.Parameters.Ligand(ligand_i).binding_state(ligand_binding_state).PDF_y = app.Parameters.PDF_input.Ligand_y;            

                end
                app.PDF_updated=1;
            end
            hold(app.PDF_plot, "off")

            
        end
        
        
        function PDF = PDF_normalise(app,P, dist,tail)
            %% Function to normalize PDFs to 1

            if nargin == 3
                tail=1;
            end
            
            P_dist = @(x) interp1(dist(1:(end-tail)),P(1:(end-tail)), x);
            x_length = max(dist(1:(end-tail)));

            c_norm = integral(@(x) (4*pi.*x.^2).*P_dist(x),0,x_length);
            if c_norm > 0
                PDF = P/c_norm;
            end

        end
    end   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Initiate_Patameters
            app.setReceptor()
        end

        % Callback function
        function Button_Receptor_1Pushed(app, event)
            app.Parameters.Receptor.Valency = 1;
            app.setReceptor()
        end

        % Callback function
        function Button_Receptor_2Pushed(app, event)
            app.Parameters.Receptor.Valency = 2;
            app.setReceptor()
        end

        % Callback function
        function Button_Receptor_3Pushed(app, event)
            app.Parameters.Receptor.Valency = 3;
            app.setReceptor()
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {821, 821};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {447, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function
        function Button_Ligand1_1Pushed(app, event)
            app.Parameters.Ligand(1).Valency = 1;
            app.setReceptor()   
        end

        % Callback function
        function Button_Ligand1_2Pushed(app, event)
            app.Parameters.Ligand(1).Valency = 2;
            app.setReceptor()          
        end

        % Callback function
        function Button_Ligand1_3Pushed(app, event)
            app.Parameters.Ligand(1).Valency = 3;
            app.setReceptor() 
        end

        % Callback function
        function Button_Ligand2_1Pushed(app, event)
            if app.Parameters.Ligand(2).Valency == 0
                app.Parameters.Ligand(2).Valency = 1;
            elseif app.Parameters.Ligand(2).Valency> 1
                app.Parameters.Ligand(2).Valency = 1;
            elseif app.Parameters.Ligand(2).Valency == 1
                app.Parameters.Ligand(2).Valency = 0;
            end
            app.setReceptor()
        end

        % Callback function
        function Button_Ligand2_2Pushed(app, event)
            app.Parameters.Ligand(2).Valency = 2;
            app.setReceptor()     
        end

        % Callback function
        function Button_Ligand2_3Pushed(app, event)
            app.Parameters.Ligand(2).Valency = 3;
            app.setReceptor()     
        end

        % Callback function
        function Button_Ligand3_1Pushed(app, event)
            if app.Parameters.Ligand(3).Valency == 0
                app.Parameters.Ligand(3).Valency = 1;
            elseif app.Parameters.Ligand(3).Valency > 1
                app.Parameters.Ligand(3).Valency = 1;
            elseif app.Parameters.Ligand(3).Valency ==1
                app.Parameters.Ligand(3).Valency = 0;
            end
            app.setReceptor()   
        end

        % Callback function
        function Button_Ligand3_2Pushed(app, event)
            app.Parameters.Ligand(3).Valency = 2;
            app.setReceptor()     
        end

        % Callback function
        function Button_Ligand3_3Pushed(app, event)
            app.Parameters.Ligand(3).Valency = 3;
            app.setReceptor()     
        end

        % Display data changed function: Table_Timepoints
        function Table_TimepointsDisplayDataChanged(app, event)
            app.Table_Timepoints.Data = app.Table_Timepoints.DisplayData;
            app.TimePoints =app.Table_Timepoints.Data;
            
            
            plot_timedata = table2array(app.TimePoints);
            for i = 1:size(plot_timedata,1)
                if i == 1
                    plotdata = [0, plot_timedata(1,2:4)];
                else
                    plotdata = [plotdata; [plot_timedata(i-1,1), plot_timedata(i,2:4)]];
                end
                plotdata = [plotdata; plot_timedata(i,:)];
            end
            
            plot(app.UIAxes_Timeseries_plot, plotdata(:,1), plotdata(:,2:4));
        end

        % Cell edit callback: Table_Timepoints
        function Table_TimepointsCellEdit(app, event)
            %indices = event.Indices;
            %newData = event.NewData;
            
        end

        % Button pushed function: AddNewTimepointButton
        function AddNewTimepointButtonPushed(app, event)
            if ~isempty(app.TimePoints)
                if app.TimepointsEditField.Value < table2array(app.TimePoints(size(app.TimePoints,1), 1))
                    app.TimepointsEditField.Value = app.TimepointsEditField.Value ...
                    + table2array(app.TimePoints(size(app.TimePoints,1), 1));
                end
            end
            
            New_data_line = table(app.TimepointsEditField.Value, ...
                app.LigandA_EditField.Value, ...
                app.LigandB_EditField.Value, ... 
                app.LigandC_EditField.Value);%, 'VariableNames',{'Time points (s)','Ligand A (M)','Ligand B (M)','Ligand C (M)'});
           
            app.TimePoints = [app.TimePoints ; New_data_line];
            %app.Table_Timepoints.RowName(1) = cell('Time 1');
            
            app.Table_Timepoints.Data = app.TimePoints;
            
            
            plot_timedata = table2array(app.TimePoints);
            for i = 1:size(plot_timedata,1)
                if i == 1
                    plotdata = [0, plot_timedata(1,2:4)];
                else
                    plotdata = [plotdata; [plot_timedata(i-1,1), plot_timedata(i,2:4)]];
                end
                plotdata = [plotdata; plot_timedata(i,:)];
            end
            
            plot(app.UIAxes_Timeseries_plot, plotdata(:,1), plotdata(:,2:4));
        end

        % Button pushed function: ResettimepointsButton
        function ResettimepointsButtonPushed(app, event)
            app.TimePoints = [];
            app.Table_Timepoints.Data = app.TimePoints;
            plot(app.UIAxes_Timeseries_plot, 0, 0);
            
        end

        % Image clicked function: TitleImage_LigandC
        function TitleImage_LigandCClicked(app, event)
            
        end

        % Image clicked function: iButton_LigandA_1
        function iButton_LigandA_1ImageClicked(app, event)
            if app.Parameters.Ligand(1).Valency == 0
                app.Parameters.Ligand(1).Valency = 1;
            elseif app.Parameters.Ligand(1).Valency > 1
                app.Parameters.Ligand(1).Valency = 1;
            elseif app.Parameters.Ligand(1).Valency ==1
                app.Parameters.Ligand(1).Valency = 0;
            end
            app.setReceptor()      
        end

        % Image clicked function: iButton_LigandA_2
        function iButton_LigandA_2ImageClicked(app, event)
            app.Parameters.Ligand(1).Valency = 2;
            app.setReceptor()  
        end

        % Image clicked function: iButton_LigandA_3
        function iButton_LigandA_3ImageClicked(app, event)
            app.Parameters.Ligand(1).Valency = 3;
            app.setReceptor()  
        end

        % Image clicked function: iButton_LigandB_1
        function iButton_LigandB_1ImageClicked(app, event)
            if app.Parameters.Ligand(2).Valency == 0
                app.Parameters.Ligand(2).Valency = 1;
            elseif app.Parameters.Ligand(2).Valency > 1
                app.Parameters.Ligand(2).Valency = 1;
            elseif app.Parameters.Ligand(2).Valency ==1
                app.Parameters.Ligand(2).Valency = 0;
            end
            app.setReceptor()               
        end

        % Image clicked function: iButton_LigandB_2
        function iButton_LigandB_2ImageClicked(app, event)
            app.Parameters.Ligand(2).Valency = 2;
            app.setReceptor()              
        end

        % Image clicked function: iButton_LigandB_3
        function iButton_LigandB_3ImageClicked(app, event)
            app.Parameters.Ligand(2).Valency = 3;
            app.setReceptor()              
        end

        % Image clicked function: iButton_LigandC_1
        function iButton_LigandC_1ImageClicked(app, event)
            if app.Parameters.Ligand(3).Valency == 0
                app.Parameters.Ligand(3).Valency = 1;
            elseif app.Parameters.Ligand(3).Valency > 1
                app.Parameters.Ligand(3).Valency = 1;
            elseif app.Parameters.Ligand(3).Valency ==1
                app.Parameters.Ligand(3).Valency = 0;
            end
            app.setReceptor()             
        end

        % Image clicked function: iButton_LigandC_2
        function iButton_LigandC_2ImageClicked(app, event)
            app.Parameters.Ligand(3).Valency = 2;
            app.setReceptor()               
        end

        % Image clicked function: iButton_LigandC_3
        function iButton_LigandC_3ImageClicked(app, event)
            app.Parameters.Ligand(3).Valency = 3;
            app.setReceptor()               
        end

        % Image clicked function: iButton_Receptor_1
        function iButton_Receptor_1ImageClicked(app, event)
            app.Parameters.Receptor.Valency = 1;
            app.setReceptor()
        end

        % Image clicked function: iButton_Receptor_2
        function iButton_Receptor_2ImageClicked(app, event)
            app.Parameters.Receptor.Valency = 2;
            app.setReceptor()            
        end

        % Image clicked function: iButton_Receptor_3
        function iButton_Receptor_3ImageClicked(app, event)
            app.Parameters.Receptor.Valency = 3;
            app.setReceptor()            
        end

        % Value changed function: Kon_LigandA_R1_L1
        function Kon_LigandA_R1_L1ValueChanged(app, event)
            value = app.Kon_LigandA_R1_L1.Value;
            app.Parameters.Kons.Kon_Receptor1_pos_1_Ligand1_pos_1 = value;
        end

        % Value changed function: Kon_LigandA_R2_L1
        function Kon_LigandA_R2_L1ValueChanged(app, event)
            value = app.Kon_LigandA_R2_L1.Value;
            app.Parameters.Kons.Kon_Receptor1_pos_2_Ligand1_pos_1 = value;
        end

        % Value changed function: Kon_LigandA_R3_L1
        function Kon_LigandA_R3_L1ValueChanged(app, event)
            value = app.Kon_LigandA_R3_L1.Value;
            app.Parameters.Kons.Kon_Receptor1_pos_3_Ligand1_pos_1 = value;
        end

        % Value changed function: Kon_LigandA_R1_L2
        function Kon_LigandA_R1_L2ValueChanged(app, event)
            value = app.Kon_LigandA_R1_L2.Value;
            app.Parameters.Kons.Kon_Receptor1_pos_1_Ligand1_pos_2 = value;
        end

        % Value changed function: Kon_LigandA_R2_L2
        function Kon_LigandA_R2_L2ValueChanged(app, event)
            value = app.Kon_LigandA_R2_L2.Value;
            app.Parameters.Kons.Kon_Receptor1_pos_2_Ligand1_pos_2 = value;
        end

        % Value changed function: Kon_LigandA_R3_L2
        function Kon_LigandA_R3_L2ValueChanged(app, event)
            value = app.Kon_LigandA_R3_L2.Value;
            app.Parameters.Kons.Kon_Receptor1_pos_3_Ligand1_pos_2 = value;
        end

        % Value changed function: Kon_LigandA_R1_L3
        function Kon_LigandA_R1_L3ValueChanged(app, event)
            value = app.Kon_LigandA_R1_L3.Value;
            app.Parameters.Kons.Kon_Receptor1_pos_1_Ligand1_pos_3 = value;
        end

        % Value changed function: Kon_LigandA_R2_L3
        function Kon_LigandA_R2_L3ValueChanged(app, event)
            value = app.Kon_LigandA_R2_L3.Value;
            app.Parameters.Kons.Kon_Receptor1_pos_2_Ligand1_pos_3 = value;
        end

        % Value changed function: Kon_LigandA_R3_L3
        function Kon_LigandA_R3_L3ValueChanged(app, event)
            value = app.Kon_LigandA_R3_L3.Value;
            app.Parameters.Kons.Kon_Receptor1_pos_3_Ligand1_pos_3 = value;
        end

        % Value changed function: Koff_LigandA_R1_L1
        function Koff_LigandA_R1_L1ValueChanged(app, event)
            value = app.Koff_LigandA_R1_L1.Value;
            app.Parameters.Koffs.Koff_Receptor1_pos_1_Ligand1_pos_1 = value;
        end

        % Value changed function: Koff_LigandA_R2_L1
        function Koff_LigandA_R2_L1ValueChanged(app, event)
            value = app.Koff_LigandA_R2_L1.Value;
            app.Parameters.Koffs.Koff_Receptor1_pos_2_Ligand1_pos_1 = value;
        end

        % Value changed function: Koff_LigandA_R3_L1
        function Koff_LigandA_R3_L1ValueChanged(app, event)
            value = app.Koff_LigandA_R3_L1.Value;
            app.Parameters.Koffs.Koff_Receptor1_pos_3_Ligand1_pos_1 = value;
        end

        % Value changed function: Koff_LigandA_R1_L2
        function Koff_LigandA_R1_L2ValueChanged(app, event)
            value = app.Koff_LigandA_R1_L2.Value;
            app.Parameters.Koffs.Koff_Receptor1_pos_1_Ligand1_pos_2 = value;
        end

        % Value changed function: Koff_LigandA_R2_L2
        function Koff_LigandA_R2_L2ValueChanged(app, event)
            value = app.Koff_LigandA_R2_L2.Value;
            app.Parameters.Koffs.Koff_Receptor1_pos_2_Ligand1_pos_2 = value;
        end

        % Value changed function: Koff_LigandA_R3_L2
        function Koff_LigandA_R3_L2ValueChanged(app, event)
            value = app.Koff_LigandA_R3_L2.Value;
            app.Parameters.Koffs.Koff_Receptor1_pos_3_Ligand1_pos_2 = value;
        end

        % Value changed function: Koff_LigandA_R1_L3
        function Koff_LigandA_R1_L3ValueChanged(app, event)
            value = app.Koff_LigandA_R1_L3.Value;
            app.Parameters.Koffs.Koff_Receptor1_pos_1_Ligand1_pos_3 = value;
        end

        % Value changed function: Koff_LigandA_R2_L3
        function Koff_LigandA_R2_L3ValueChanged(app, event)
            value = app.Koff_LigandA_R2_L3.Value;
            app.Parameters.Koffs.Koff_Receptor1_pos_2_Ligand1_pos_3 = value;
        end

        % Value changed function: Koff_LigandA_R3_L3
        function Koff_LigandA_R3_L3ValueChanged(app, event)
            value = app.Koff_LigandA_R3_L3.Value;
            app.Parameters.Koffs.Koff_Receptor1_pos_3_Ligand1_pos_3 = value;
        end

        % Value changed function: MolecularWeight_LA
        function MolecularWeight_LAValueChanged(app, event)
            value = app.MolecularWeight_LA.Value;
            app.Parameters.Ligand(1).MolecularWeight = value;
        end

        % Value changed function: UnitDiameter1_LA
        function UnitDiameter1_LAValueChanged(app, event)
            value = app.UnitDiameter1_LA.Value;
             app.Parameters.Ligand(1).Diameter(1) = value;
             app.rerunable = 0;
            app.RerunButton.Enable = false;
        end

        % Value changed function: UnitDiameter2_LA
        function UnitDiameter2_LAValueChanged(app, event)
            value = app.UnitDiameter2_LA.Value;
            app.Parameters.Ligand(1).Diameter(2) = value;
            app.rerunable = 0;
            app.RerunButton.Enable = false;
        end

        % Value changed function: UnitDiameter3_LA
        function UnitDiameter3_LAValueChanged(app, event)
            value = app.UnitDiameter3_LA.Value;
            app.Parameters.Ligand(1).Diameter(3) = value;
            app.rerunable = 0;
            app.RerunButton.Enable = false;
        end

        % Value changed function: LinkerLength1_LA
        function LinkerLength1_LAValueChanged(app, event)
            value = app.LinkerLength1_LA.Value;
            app.Parameters.Ligand(1).LinkerLength(1) = value;
            app.rerunable = 0;
            app.RerunButton.Enable = false;
        end

        % Value changed function: LinkerLength2_LA
        function LinkerLength2_LAValueChanged(app, event)
            value = app.LinkerLength2_LA.Value;
            app.Parameters.Ligand(1).LinkerLength(2) = value;
            app.rerunable = 0;
            app.RerunButton.Enable = false;
        end

        % Value changed function: PersistanceLength1_LA
        function PersistanceLength1_LAValueChanged(app, event)
            value = app.PersistanceLength1_LA.Value;
            app.Parameters.Ligand(1).LinkerLP(1) = value;
            app.rerunable = 0;
            app.RerunButton.Enable = false;
        end

        % Value changed function: PersistanceLength2_LA
        function PersistanceLength2_LAValueChanged(app, event)
            value = app.PersistanceLength2_LA.Value;
            app.Parameters.Ligand(1).LinkerLP(2) = value;
            app.rerunable = 0;
            app.RerunButton.Enable = false;
        end

        % Value changed function: MolecularWeight_LB
        function MolecularWeight_LBValueChanged(app, event)
            value = app.MolecularWeight_LB.Value;
            app.Parameters.Ligand(2).MolecularWeight = value;
        end

        % Value changed function: Kon_LigandB_R1_L1
        function Kon_LigandB_R1_L1ValueChanged(app, event)
            value = app.Kon_LigandB_R1_L1.Value;
            app.Parameters.Kons.Kon_Receptor1_pos_1_Ligand2_pos_1 = value;
        end

        % Value changed function: Kon_LigandB_R2_L1
        function Kon_LigandB_R2_L1ValueChanged(app, event)
            value = app.Kon_LigandB_R2_L1.Value;
            app.Parameters.Kons.Kon_Receptor1_pos_2_Ligand2_pos_1 = value;
        end

        % Value changed function: Kon_LigandB_R3_L1
        function Kon_LigandB_R3_L1ValueChanged(app, event)
            value = app.Kon_LigandB_R3_L1.Value;
            app.Parameters.Kons.Kon_Receptor1_pos_3_Ligand2_pos_1 = value;
        end

        % Value changed function: Kon_LigandB_R1_L2
        function Kon_LigandB_R1_L2ValueChanged(app, event)
            value = app.Kon_LigandB_R1_L2.Value;
            app.Parameters.Kons.Kon_Receptor1_pos_1_Ligand2_pos_2 = value;
        end

        % Value changed function: Kon_LigandB_R2_L2
        function Kon_LigandB_R2_L2ValueChanged(app, event)
            value = app.Kon_LigandB_R2_L2.Value;
            app.Parameters.Kons.Kon_Receptor1_pos_2_Ligand2_pos_2 = value;
        end

        % Value changed function: Kon_LigandB_R3_L2
        function Kon_LigandB_R3_L2ValueChanged(app, event)
            value = app.Kon_LigandB_R3_L2.Value;
            app.Parameters.Kons.Kon_Receptor1_pos_3_Ligand2_pos_2 = value;
        end

        % Value changed function: Kon_LigandB_R1_L3
        function Kon_LigandB_R1_L3ValueChanged(app, event)
            value = app.Kon_LigandB_R1_L3.Value;
            app.Parameters.Kons.Kon_Receptor1_pos_1_Ligand2_pos_3 = value;
        end

        % Value changed function: Kon_LigandB_R2_L3
        function Kon_LigandB_R2_L3ValueChanged(app, event)
            value = app.Kon_LigandB_R2_L3.Value;
            app.Parameters.Kons.Kon_Receptor1_pos_2_Ligand2_pos_3 = value;
        end

        % Value changed function: Kon_LigandB_R3_L3
        function Kon_LigandB_R3_L3ValueChanged(app, event)
            value = app.Kon_LigandB_R3_L3.Value;
            app.Parameters.Kons.Kon_Receptor1_pos_3_Ligand2_pos_3 = value;
        end

        % Value changed function: UnitDiameter1_LC
        function UnitDiameter1_LCValueChanged(app, event)
            value = app.UnitDiameter1_LC.Value;
            app.Parameters.Ligand(3).Diameter(1) = value;
            app.rerunable = 0;
            app.RerunButton.Enable = false;
        end

        % Value changed function: UnitDiameter2_LC
        function UnitDiameter2_LCValueChanged(app, event)
            value = app.UnitDiameter2_LC.Value;
            app.Parameters.Ligand(3).Diameter(2) = value;
            app.rerunable = 0;
            app.RerunButton.Enable = false;
        end

        % Value changed function: UnitDiameter3_LC
        function UnitDiameter3_LCValueChanged(app, event)
            value = app.UnitDiameter3_LC.Value;
            app.Parameters.Ligand(3).Diameter(3) = value;
            app.rerunable = 0;
            app.RerunButton.Enable = false;
        end

        % Value changed function: LinkerLength1_LC
        function LinkerLength1_LCValueChanged(app, event)
            value = app.LinkerLength1_LC.Value;
            app.Parameters.Ligand(3).LinkerLength(1) = value;
            app.rerunable = 0;
            app.RerunButton.Enable = false;
        end

        % Value changed function: LinkerLength2_LC
        function LinkerLength2_LCValueChanged(app, event)
            value = app.LinkerLength2_LC.Value;
            app.Parameters.Ligand(3).LinkerLength(2) = value;
            app.rerunable = 0;
            app.RerunButton.Enable = false;
        end

        % Value changed function: PersistanceLength1_LC
        function PersistanceLength1_LCValueChanged(app, event)
            value = app.PersistanceLength1_LC.Value;
            app.Parameters.Ligand(3).LinkerLP(1) = value;
            app.rerunable = 0;
            app.RerunButton.Enable = false;
        end

        % Value changed function: PersistanceLength2_LC
        function PersistanceLength2_LCValueChanged(app, event)
            value = app.PersistanceLength2_LC.Value;
            app.Parameters.Ligand(3).LinkerLP(2) = value;
            app.rerunable = 0;
            app.RerunButton.Enable = false;
        end

        % Value changed function: LinkerLength1_LB
        function LinkerLength1_LBValueChanged(app, event)
            value = app.LinkerLength1_LB.Value;
            app.Parameters.Ligand(2).LinkerLength(1) = value;     
            app.rerunable = 0;
            app.RerunButton.Enable = false;
        end

        % Value changed function: LinkerLength2_LB
        function LinkerLength2_LBValueChanged(app, event)
            value = app.LinkerLength2_LB.Value;
            app.Parameters.Ligand(2).LinkerLength(2) = value;
            app.rerunable = 0;
            app.RerunButton.Enable = false;
        end

        % Value changed function: UnitDiameter1_LB
        function UnitDiameter1_LBValueChanged(app, event)
            value = app.UnitDiameter1_LB.Value;
            app.Parameters.Ligand(2).Diameter(1) = value;
            app.rerunable = 0;
            app.RerunButton.Enable = false;
        end

        % Value changed function: UnitDiameter2_LB
        function UnitDiameter2_LBValueChanged(app, event)
            value = app.UnitDiameter2_LB.Value;
            app.Parameters.Ligand(2).Diameter(2) = value;
            app.rerunable = 0;
            app.RerunButton.Enable = false;
        end

        % Value changed function: UnitDiameter3_LB
        function UnitDiameter3_LBValueChanged(app, event)
            value = app.UnitDiameter3_LB.Value;
            app.Parameters.Ligand(2).Diameter(3) = value;
            app.rerunable = 0;
            app.RerunButton.Enable = false;
        end

        % Value changed function: PersistanceLength1_LB
        function PersistanceLength1_LBValueChanged(app, event)
            value = app.PersistanceLength1_LB.Value;
            app.Parameters.Ligand(2).LinkerLP(1) = value;
            app.rerunable = 0;
            app.RerunButton.Enable = false;
        end

        % Value changed function: PersistanceLength2_LB
        function PersistanceLength2_LBValueChanged(app, event)
            value = app.PersistanceLength2_LB.Value;
            app.Parameters.Ligand(2).LinkerLP(2) = value;
            app.rerunable = 0;
            app.RerunButton.Enable = false;
        end

        % Value changed function: Kon_LigandC_R1_L1
        function Kon_LigandC_R1_L1ValueChanged(app, event)
            value = app.Kon_LigandC_R1_L1.Value;
            app.Parameters.Kons.Kon_Receptor1_pos_1_Ligand3_pos_1 = value;
        end

        % Value changed function: Kon_LigandC_R2_L1
        function Kon_LigandC_R2_L1ValueChanged(app, event)
            value = app.Kon_LigandC_R2_L1.Value;
            app.Parameters.Kons.Kon_Receptor1_pos_2_Ligand3_pos_1 = value;
        end

        % Value changed function: Kon_LigandC_R3_L1
        function Kon_LigandC_R3_L1ValueChanged(app, event)
            value = app.Kon_LigandC_R3_L1.Value;
            app.Parameters.Kons.Kon_Receptor1_pos_3_Ligand3_pos_1 = value;
        end

        % Value changed function: Kon_LigandC_R1_L2
        function Kon_LigandC_R1_L2ValueChanged(app, event)
            value = app.Kon_LigandC_R1_L2.Value;
            app.Parameters.Kons.Kon_Receptor1_pos_1_Ligand3_pos_2 = value;
        end

        % Value changed function: Kon_LigandC_R2_L2
        function Kon_LigandC_R2_L2ValueChanged(app, event)
            value = app.Kon_LigandC_R2_L2.Value;
            app.Parameters.Kons.Kon_Receptor1_pos_2_Ligand3_pos_2 = value;
        end

        % Value changed function: Kon_LigandC_R3_L2
        function Kon_LigandC_R3_L2ValueChanged(app, event)
            value = app.Kon_LigandC_R3_L2.Value;
            app.Parameters.Kons.Kon_Receptor1_pos_3_Ligand3_pos_2 = value;
        end

        % Value changed function: Kon_LigandC_R1_L3
        function Kon_LigandC_R1_L3ValueChanged(app, event)
            value = app.Kon_LigandC_R1_L3.Value;
            app.Parameters.Kons.Kon_Receptor1_pos_1_Ligand3_pos_3 = value;
        end

        % Value changed function: Kon_LigandC_R2_L3
        function Kon_LigandC_R2_L3ValueChanged(app, event)
            value = app.Kon_LigandC_R2_L3.Value;
            app.Parameters.Kons.Kon_Receptor1_pos_2_Ligand3_pos_3 = value;
        end

        % Value changed function: Kon_LigandC_R3_L3
        function Kon_LigandC_R3_L3ValueChanged(app, event)
            value = app.Kon_LigandC_R3_L3.Value;
            app.Parameters.Kons.Kon_Receptor1_pos_3_Ligand3_pos_3 = value;
        end

        % Value changed function: MolecularWeight_Receptor
        function MolecularWeight_ReceptorValueChanged(app, event)
            value = app.MolecularWeight_Receptor.Value;
            app.Parameters.Receptor(1).MolecularWeight = value;
            app.Parameters.SPR.baseR0 = ((app.Parameters.SPR.baseRU * app.Parameters.constants.RU2g )/ app.Parameters.Receptor(1).MolecularWeight )/(app.Parameters.constants.Volume * 1000);
            %app.Parameters.SPR.RUmod= ((app.Parameters.constants.RU2g )/ app.Parameters.Ligand(1).MolecularWeight )/(app.Parameters.constants.Volume * 1000);
            app.RU_Receptor.Value = app.Parameters.SPR.baseRU;
            app.ReceptorConcentration_Receptor_4.Value = app.Parameters.SPR.baseR0;
        end

        % Value changed function: UnitDiameter1_Receptor
        function UnitDiameter1_ReceptorValueChanged(app, event)
            value = app.UnitDiameter1_Receptor.Value;
            app.Parameters.Receptor(1).Diameter(1) = value;
            app.rerunable = 0;
            app.RerunButton.Enable = false;
        end

        % Value changed function: UnitDiameter2_Receptor
        function UnitDiameter2_ReceptorValueChanged(app, event)
            value = app.UnitDiameter2_Receptor.Value;
            app.Parameters.Receptor(1).Diameter(2) = value;
            app.rerunable = 0;
            app.RerunButton.Enable = false;
        end

        % Value changed function: UnitDiameter3_Receptor
        function UnitDiameter3_ReceptorValueChanged(app, event)
            value = app.UnitDiameter3_Receptor.Value;
            app.Parameters.Receptor(1).Diameter(3) = value;
            app.rerunable = 0;
            app.RerunButton.Enable = false;
        end

        % Value changed function: LinkerLength1_Receptor
        function LinkerLength1_ReceptorValueChanged(app, event)
            value = app.LinkerLength1_Receptor.Value;
            app.Parameters.Receptor(1).LinkerLength(1) = value;
            app.rerunable = 0;
            app.RerunButton.Enable = false;
        end

        % Value changed function: LinkerLength2_Receptor
        function LinkerLength2_ReceptorValueChanged(app, event)
            value = app.LinkerLength2_Receptor.Value;
            app.Parameters.Receptor(1).LinkerLength(2) = value;
            app.rerunable = 0;
            app.RerunButton.Enable = false;
        end

        % Value changed function: PersistanceLength1_Receptor
        function PersistanceLength1_ReceptorValueChanged(app, event)
            value = app.PersistanceLength1_Receptor.Value;
            app.Parameters.Receptor(1).LinkerLP(1) = value;
            app.rerunable = 0;
            app.RerunButton.Enable = false;
        end

        % Value changed function: PersistanceLength2_Receptor
        function PersistanceLength2_ReceptorValueChanged(app, event)
            value = app.PersistanceLength2_Receptor.Value;
            app.Parameters.Receptor(1).LinkerLP(2) = value;
            app.rerunable = 0;
            app.RerunButton.Enable = false;
        end

        % Button pushed function: InitiateandRunButton
        function InitiateandRunButtonPushed(app, event)
            
            %app.update_rateconstants;
            %1)
            if isfield(app.Parameters.Ligand, 'binding_state')
            %app.Parameters = rmfield(app.Parameters, {'EffCs','Effective_concentrations','Koffs','Kons'});
            app.Parameters.Ligand = rmfield(app.Parameters.Ligand, 'binding_state');
            app.Parameters.Receptor = rmfield(app.Parameters.Receptor, 'binding_state');
            end
            app.setReceptor;
            app.ProgressLabel.Text = "Initiating";
            app.ProgressButton.Position(3)=30;
                app.initiated_plotdata = 1;
                app.copy_all_parameters_from_default;
            %2)
            app.ProgressLabel.Text = "Effective concentration calculation";
            app.ProgressButton.Position(3)=30;
            drawnow()
            
                EffC_Calculator_polar(app)
                effC_name_converter(app);
                
                
            %3)COncentration
            app.ProgressLabel.Text = "Calculating curves";
            app.ProgressButton.Position(3)=200;
            drawnow()
                
                app.Output_concentraion = GUI_Manual_input3x3x3(app);
                
            %4)Plotting
            app.ProgressLabel.Text = "Plotting";
            app.ProgressButton.Position(3)=310;
            drawnow()
                app.PDF_plot_curves("", 0)
                app.WebAppPlot;
                
            %5) Finished
            app.ProgressButton.Position(3)=340;
            app.ProgressLabel.Text = "Ready";
            %save("Parameters", "app")
            app.rerunable = 1;
            app.PDF_updated =0;
            app.RerunButton.Enable = true;
            
            app.UITable.Data = rows2vars(struct2table(app.Parameters.EffCs));
             
%              plot(app.Axis_MassCurve_main, app.Output_concentraion.States.time, sum(app.Output_concentraion.States.RU, 2));
% %             legend(app.UIAxes_Series_large, string(gparams.SPR.series));
%              plot(app.Axis_SignalComponents_main, app.Output_concentraion.States.time, app.Output_concentraion.States.RU);
% %             legend(app.UIAxes_Series, string(gparams.SPR.series));
% %             
%              plot(app.Axis_MassCurve_separate, app.Output_concentraion.States.time, sum(app.Output_concentraion.States.RU, 2));
% %             legend(app.UIAxes_States_large,string(unique(gparams.base.State_classes)));
%              plot(app.Axis_SignalComponents_separate, app.Output_concentraion.States.time, app.Output_concentraion.States.RU);
% %             legend(app.UIAxes_States,string(unique(gparams.base.State_classes)));
%               plot(app.Axis_GroupedStates_main, app.Output_concentraion.States.time, app.Output_concentraion.Ligand_Classes.RU(:,2:end))
%               legend(app.Axis_GroupedStates_main, "A","B","C","AB","AC","BC","ABC")
%             plot(app.UIAxes_Graph, app.Output_concentraion.C_series.time, app.Output_concentraion.C_series.conc(:,1));
%             app.graph_plot;
%             app.Slider_main.Limits = [0,app.Output_concentraion.States.time(end)];
%             app.Slider_main.MinorTicks = app.Output_concentraion.States.time;
%             app.Slider_2.Limits = [0,app.Output_concentraion.States.time(end)];
%             app.Slider_2.MinorTicks = app.Output_concentraion.States.time;
%             
%             app.Progress_bar.Position(3)=310;
%             app.Progress_text.Text = 'Finished';
        end

        % Value changed function: MolecularWeight_LC
        function MolecularWeight_LCValueChanged(app, event)
            value = app.MolecularWeight_LC.Value;
            app.Parameters.Ligand(3).MolecularWeight = value;
        end

        % Value changed function: RU_Receptor
        function RU_ReceptorValueChanged(app, event)
            value = app.RU_Receptor.Value;
            app.Parameters.SPR.baseRU = value;
            app.Parameters.SPR.baseR0 = ((app.Parameters.SPR.baseRU * app.Parameters.constants.RU2g )/ app.Parameters.Receptor(1).MolecularWeight )/(app.Parameters.constants.Volume * 1000);
            app.RU_Receptor.Value = app.Parameters.SPR.baseRU;
            app.ReceptorConcentration_Receptor_4.Value = app.Parameters.SPR.baseR0;
        end

        % Value changed function: Koff_LigandB_R1_L1
        function Koff_LigandB_R1_L1ValueChanged(app, event)
            value = app.Koff_LigandB_R1_L1.Value;
            app.Parameters.Koffs.Koff_Receptor1_pos_1_Ligand2_pos_1 = value;
        end

        % Value changed function: Koff_LigandB_R2_L1
        function Koff_LigandB_R2_L1ValueChanged(app, event)
            value = app.Koff_LigandB_R2_L1.Value;
            app.Parameters.Koffs.Koff_Receptor1_pos_2_Ligand2_pos_1 = value;
        end

        % Value changed function: Koff_LigandB_R3_L1
        function Koff_LigandB_R3_L1ValueChanged(app, event)
            value = app.Koff_LigandB_R3_L1.Value;
            app.Parameters.Koffs.Koff_Receptor1_pos_3_Ligand2_pos_1 = value;
        end

        % Value changed function: Koff_LigandB_R1_L2
        function Koff_LigandB_R1_L2ValueChanged(app, event)
            value = app.Koff_LigandB_R1_L2.Value;
            app.Parameters.Koffs.Koff_Receptor1_pos_1_Ligand2_pos_2 = value;
        end

        % Value changed function: Koff_LigandB_R2_L2
        function Koff_LigandB_R2_L2ValueChanged(app, event)
            value = app.Koff_LigandB_R2_L2.Value;
            app.Parameters.Koffs.Koff_Receptor1_pos_2_Ligand2_pos_2 = value;
        end

        % Value changed function: Koff_LigandB_R3_L2
        function Koff_LigandB_R3_L2ValueChanged(app, event)
            value = app.Koff_LigandB_R3_L2.Value;
            app.Parameters.Koffs.Koff_Receptor1_pos_3_Ligand2_pos_2 = value;
        end

        % Value changed function: Koff_LigandB_R1_L3
        function Koff_LigandB_R1_L3ValueChanged(app, event)
            value = app.Koff_LigandB_R1_L3.Value;
            app.Parameters.Koffs.Koff_Receptor1_pos_1_Ligand2_pos_3 = value;
        end

        % Value changed function: Koff_LigandB_R2_L3
        function Koff_LigandB_R2_L3ValueChanged(app, event)
            value = app.Koff_LigandB_R2_L3.Value;
            app.Parameters.Koffs.Koff_Receptor1_pos_2_Ligand2_pos_3 = value;
        end

        % Value changed function: Koff_LigandB_R3_L3
        function Koff_LigandB_R3_L3ValueChanged(app, event)
            value = app.Koff_LigandB_R3_L3.Value;
            app.Parameters.Koffs.Koff_Receptor1_pos_3_Ligand2_pos_3 = value;
        end

        % Value changed function: Koff_LigandC_R1_L1
        function Koff_LigandC_R1_L1ValueChanged(app, event)
            value = app.Koff_LigandC_R1_L1.Value;
            app.Parameters.Koffs.Koff_Receptor1_pos_1_Ligand3_pos_1 = value;
        end

        % Value changed function: Koff_LigandC_R2_L1
        function Koff_LigandC_R2_L1ValueChanged(app, event)
            value = app.Koff_LigandC_R2_L1.Value;
            app.Parameters.Koffs.Koff_Receptor1_pos_2_Ligand3_pos_1 = value;
        end

        % Value changed function: Koff_LigandC_R3_L1
        function Koff_LigandC_R3_L1ValueChanged(app, event)
            value = app.Koff_LigandC_R3_L1.Value;
            app.Parameters.Koffs.Koff_Receptor1_pos_3_Ligand3_pos_1 = value;
        end

        % Value changed function: Koff_LigandC_R1_L2
        function Koff_LigandC_R1_L2ValueChanged(app, event)
            value = app.Koff_LigandC_R1_L2.Value;
            app.Parameters.Koffs.Koff_Receptor1_pos_1_Ligand3_pos_2 = value;
        end

        % Value changed function: Koff_LigandC_R2_L2
        function Koff_LigandC_R2_L2ValueChanged(app, event)
            value = app.Koff_LigandC_R2_L2.Value;
            app.Parameters.Koffs.Koff_Receptor1_pos_2_Ligand3_pos_2 = value;
        end

        % Value changed function: Koff_LigandC_R3_L2
        function Koff_LigandC_R3_L2ValueChanged(app, event)
            value = app.Koff_LigandC_R3_L2.Value;
            app.Parameters.Koffs.Koff_Receptor1_pos_3_Ligand3_pos_2 = value;
        end

        % Value changed function: Koff_LigandC_R1_L3
        function Koff_LigandC_R1_L3ValueChanged(app, event)
            value = app.Koff_LigandC_R1_L3.Value;
            app.Parameters.Koffs.Koff_Receptor1_pos_1_Ligand3_pos_3 = value;
        end

        % Value changed function: Koff_LigandC_R2_L3
        function Koff_LigandC_R2_L3ValueChanged(app, event)
            value = app.Koff_LigandC_R2_L3.Value;
            app.Parameters.Koffs.Koff_Receptor1_pos_2_Ligand3_pos_3 = value;
        end

        % Value changed function: Koff_LigandC_R3_L3
        function Koff_LigandC_R3_L3ValueChanged(app, event)
            value = app.Koff_LigandC_R3_L3.Value;
            app.Parameters.Koffs.Koff_Receptor1_pos_3_Ligand3_pos_3 = value;
        end

        % Value changed function: CheckBox_Main_M
        function CheckBox_Main_MValueChanged(app, event)
            value = app.CheckBox_Main_M.Value;
            if value == 1
                app.Checkbox_flip("CONC"); 
            else
                app.Checkbox_flip("RU"); 
            end
            if app.initiated_plotdata ==1
                %4)Plotting
                app.ProgressLabel.Text = "Plotting";
                app.ProgressButton.Position(3)=310;
                drawnow()
                app.WebAppPlot;
                
            %5) Finished
                app.ProgressButton.Position(3)=340;
                app.ProgressLabel.Text = "Ready";
            end 
        end

        % Value changed function: CheckBox_TotalSignal_M
        function CheckBox_TotalSignal_MValueChanged(app, event)
            value = app.CheckBox_TotalSignal_M.Value;
            if value == 1
                app.Checkbox_flip("CONC"); 
            else
                app.Checkbox_flip("RU"); 
            end  
            if app.initiated_plotdata ==1
                %4)Plotting
                app.ProgressLabel.Text = "Plotting";
                app.ProgressButton.Position(3)=310;
                drawnow()
                app.WebAppPlot;
                
            %5) Finished
                app.ProgressButton.Position(3)=340;
                app.ProgressLabel.Text = "Ready";
            end 
        end

        % Value changed function: CheckBox_MicroStates_M
        function CheckBox_MicroStates_MValueChanged(app, event)
            value = app.CheckBox_MicroStates_M.Value;
            if value == 1
                app.Checkbox_flip("CONC"); 
            else
                app.Checkbox_flip("RU"); 
            end  
            if app.initiated_plotdata ==1
                %4)Plotting
                app.ProgressLabel.Text = "Plotting";
                app.ProgressButton.Position(3)=310;
                drawnow()
                app.WebAppPlot;
                
            %5) Finished
                app.ProgressButton.Position(3)=340;
                app.ProgressLabel.Text = "Ready";
            end 
        end

        % Value changed function: CheckBox_Grouped_M
        function CheckBox_Grouped_MValueChanged(app, event)
            value = app.CheckBox_Grouped_M.Value;
            if value == 1
                app.Checkbox_flip("CONC"); 
            else
                app.Checkbox_flip("RU"); 
            end    
            if app.initiated_plotdata ==1
                %4)Plotting
                app.ProgressLabel.Text = "Plotting";
                app.ProgressButton.Position(3)=310;
                drawnow()
                app.WebAppPlot;
                
            %5) Finished
                app.ProgressButton.Position(3)=340;
                app.ProgressLabel.Text = "Ready";
            end       
        end

        % Value changed function: CheckBox_Main_RU
        function CheckBox_Main_RUValueChanged(app, event)
            value = app.CheckBox_Main_RU.Value;
            if value == 1
                app.Checkbox_flip("RU"); 
            else
                app.Checkbox_flip("CONC"); 
            end
            if app.initiated_plotdata ==1
                %4)Plotting
                app.ProgressLabel.Text = "Plotting";
                app.ProgressButton.Position(3)=310;
                drawnow()
                app.WebAppPlot;
                
            %5) Finished
                app.ProgressButton.Position(3)=340;
                app.ProgressLabel.Text = "Ready";
            end    
        end

        % Value changed function: CheckBox_TotalSignal_RU
        function CheckBox_TotalSignal_RUValueChanged(app, event)
            value = app.CheckBox_TotalSignal_RU.Value;
            if value == 1
                app.Checkbox_flip("RU"); 
            else
                app.Checkbox_flip("CONC"); 
            end
            if app.initiated_plotdata ==1
                %4)Plotting
                app.ProgressLabel.Text = "Plotting";
                app.ProgressButton.Position(3)=310;
                drawnow()
                app.WebAppPlot;
                
            %5) Finished
                app.ProgressButton.Position(3)=340;
                app.ProgressLabel.Text = "Ready";
            end 
        end

        % Value changed function: CheckBox_MicroStates_RU
        function CheckBox_MicroStates_RUValueChanged(app, event)
            value = app.CheckBox_MicroStates_RU.Value;
            if value == 1
                app.Checkbox_flip("RU"); 
            else
                app.Checkbox_flip("CONC"); 
            end
            if app.initiated_plotdata ==1
                %4)Plotting
                app.ProgressLabel.Text = "Plotting";
                app.ProgressButton.Position(3)=310;
                drawnow()
                app.WebAppPlot;
                
            %5) Finished
                app.ProgressButton.Position(3)=340;
                app.ProgressLabel.Text = "Ready";
            end 
        end

        % Value changed function: CheckBox_Grouped_RU
        function CheckBox_Grouped_RUValueChanged(app, event)
            value = app.CheckBox_Grouped_RU.Value;
            if value == 1
                app.Checkbox_flip("RU"); 
            else
                app.Checkbox_flip("CONC"); 
            end
            if app.initiated_plotdata ==1
                %4)Plotting
                app.ProgressLabel.Text = "Plotting";
                app.ProgressButton.Position(3)=310;
                drawnow()
                app.WebAppPlot;
                
            %5) Finished
                app.ProgressButton.Position(3)=340;
                app.ProgressLabel.Text = "Ready";
            end 
        end

        % Button pushed function: SaveFigureButton
        function SaveFigureButtonPushed(app, event)
            if app.initiated_plotdata == 1
                [file, path] = uiputfile("Mass_curve.fig");
                fig = figure;
                copyUIAxes(app.Axis_MassCurve_separate, fig);
                savefig(fig, strcat(path,file));
                delete(fig);                
            end
        end

        % Button pushed function: SaveFigureButton_2
        function SaveFigureButton_2Pushed(app, event)
            if app.initiated_plotdata == 1
                [file, path] = uiputfile("SignalComponent_curve.fig");
                fig = figure;
                copyUIAxes(app.Axis_SignalComponents_separate, fig);
                savefig(fig, strcat(path,file));
                delete(fig);
            end
        end

        % Button pushed function: SaveFigureButton_3
        function SaveFigureButton_3Pushed(app, event)
            if app.initiated_plotdata == 1
                [file, path] = uiputfile("Group_curve.fig");
                fig = figure;
                copyUIAxes(app.Axis_GroupedStates_separate, fig);
                savefig(fig, strcat(path,file));
                delete(fig);
            end            
        end

        % Callback function
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            app.Parameters.Plotting.PlotClass = value;
            app.WebAppPlot
        end

        % Value changed function: ClassesDropDown
        function ClassesDropDownValueChanged(app, event)
            value = app.ClassesDropDown.Value;
            app.Parameters.Plotting.PlotClass = value;
            app.group_plot;
        end

        % Value changed function: Slider_main
        function Slider_mainValueChanged(app, event)
            value = app.Slider_main.Value;
            %app.Output_concentraion.States.time, app.Output_concentraion.Valency_Classes.conc            
            if  app.initiated_plotdata == 1
                if app.Parameters.Plotting.NetworkPlotClass == "Ligand"
                    time_index = find(app.Output_concentraion.States.time<=value, 1,'last');
                    
                    plot_conc = app.Output_concentraion.Ligand_Classes.conc(:,2:end);
                    plot_conc(plot_conc < 1e-12) = 1e-12;
                    plot_conc = log10(plot_conc)+12;                   
                    
                    colours = colormap(app.Axis_Network_main,'jet');
                    for node = 1:size(app.Output_concentraion.Ligand_Classes.conc(:,2:end),2)
                        color_rank = ceil(255*plot_conc(time_index,node)/max(max(plot_conc)));
                        highlight(app.Axis_Network_main.Children, node, 'NodeColor', colours(color_rank+1,:))
                    end
                elseif app.Parameters.Plotting.NetworkPlotClass == "Valency"
                    time_index = find(app.Output_concentraion.States.time<=value, 1,'last');
                    plot_conc = app.Output_concentraion.Valency_Classes.conc;
                    plot_conc(plot_conc < 1e-12) = 1e-12;
                    plot_conc = log10(plot_conc)+12;                   
                    
                    colours = colormap(app.Axis_Network_main,'jet');
                    for node = 1:size(app.Output_concentraion.Valency_Classes.conc,2)
                        color_rank = ceil(255*plot_conc(time_index,node)/max(max(plot_conc)));
                        highlight(app.Axis_Network_main.Children, node, 'NodeColor', colours(color_rank+1,:))
                    end                    
                end
                bar_main = colorbar(app.Axis_Network_main);
                %bar_main.Limits = [min(min(plot_conc)),ceil(max(max(plot_conc)))];
                TickLabels = [{'10^{-12}';'10^{-11}';'10^{-10}';'10^{-9}';'10^{-8}';'10^{-7}';'10^{-6}'; '10^{-5}';'10^{-4}';'0.001';'0.01';'0.1';'10';'100';}];
                bar_main.TickLabels = TickLabels(1:(ceil(max(max(plot_conc)))+1)); 
                bar_main.Ticks = 0:(1/ceil(max(max(plot_conc)))):1;
                
            end
        end

        % Callback function
        function Slider_2ValueChanged(app, event)
            %value = app.Slider_2.Value;  
            %Cant delete
        end

        % Value changed function: Slider_separate
        function Slider_separateValueChanged(app, event)
            value = app.Slider_separate.Value;
            %app.Output_concentraion.States.time, app.Output_concentraion.Valency_Classes.conc            
            if  app.initiated_plotdata == 1
                if app.Parameters.Plotting.NetworkPlotClass == "Ligand"
                    time_index = find(app.Output_concentraion.States.time<=value, 1,'last');
                    plot_conc = app.Output_concentraion.Ligand_Classes.conc(:,2:end);
                    plot_conc(plot_conc < 1e-12) = 1e-12;
                    plot_conc = log10(plot_conc)+12;                   
                    
                    colours = colormap(app.Axis_Network_separate,'jet');
                    for node = 1:size(app.Output_concentraion.Ligand_Classes.conc(:,2:end),2)
                        color_rank = ceil(255*plot_conc(time_index,node)/max(max(plot_conc)));
                        highlight(app.Axis_Network_separate.Children, node, 'NodeColor', colours(color_rank+1,:))
                    end
                elseif app.Parameters.Plotting.NetworkPlotClass == "Valency"
                    time_index = find(app.Output_concentraion.States.time<=value, 1,'last');
                    plot_conc = app.Output_concentraion.Valency_Classes.conc;
                    plot_conc(plot_conc < 1e-6) = 1e-6;
                    plot_conc = log10(plot_conc)+6;                   
                    
                    colours = colormap(app.Axis_Network_separate,'jet');
                    for node = 1:size(app.Output_concentraion.Valency_Classes.conc,2)
                        color_rank = ceil(255*plot_conc(time_index,node)/max(max(plot_conc)));
                        highlight(app.Axis_Network_separate.Children, node, 'NodeColor', colours(color_rank+1,:))
                    end                    
                end
                bar_main = colorbar(app.Axis_Network_separate);
                %bar_main.Limits = [min(min(plot_conc)),ceil(max(max(plot_conc)))];
                TickLabels = [{'10^{-12}';'10^{-11}';'10^{-10}';'10^{-9}';'10^{-8}';'10^{-7}';'10^{-6}'; '10^{-5}';'10^{-4}';'0.001';'0.01';'0.1';'10';'100';}];
                bar_main.TickLabels = TickLabels(1:(ceil(max(max(plot_conc)))+1)); 
                bar_main.Ticks = 0:(1/(ceil(max(max(plot_conc))))):1;
            end
        end

        % Button pushed function: UpdateTableButton
        function UpdateTableButtonPushed(app, event)
            if app.initiated_plotdata == 1
                if app.export_info == 1                
                    app.UITable.Data = struct2table(app.Parameters.Effective_concentrations.EffC);
                    app.UITable.ColumnName = struct2table(app.Parameters.Effective_concentrations.EffC).Properties.VariableNames;
                    
                else
                    app.UITable.Data = rows2vars(struct2table(app.Parameters.EffCs));
                    %app.UITable.ColumnName = rows2vars(struct2table(app.Parameters.EffCs)).Properties.VariableNames;
                    
                end
            end
        end

        % Callback function
        function ClassesDropDown_2ValueChanged(app, event)
            value = app.ClassesDropDown_2.Value;
            "additional function"
            app.Parameters.Plotting.NetworkPlotClass = value;
            app.WebAppPlot
        end

        % Value changed function: ClassesDropDown_2
        function ClassesDropDown_2ValueChanged2(app, event)
            value = app.ClassesDropDown_2.Value;
            app.Parameters.Plotting.NetworkPlotClass = value;
            app.WebAppPlot
        end

        % Callback function
        function Slider_mainValueChanged2(app, event)
            
        end

        % Callback function
        function Slider_separateValueChanged2(app, event)
            
        end

        % Value changed function: CheckBox_Netw_RU
        function CheckBox_Netw_RUValueChanged(app, event)
            value = app.CheckBox_Netw_RU.Value;
            if value == 1
                app.Checkbox_flip("RU"); 
            else
                app.Checkbox_flip("CONC"); 
            end
            if app.initiated_plotdata ==1
                %4)Plotting
                app.ProgressLabel.Text = "Plotting";
                app.ProgressButton.Position(3)=310;
                drawnow()
                app.WebAppPlot;
                
            %5) Finished
                app.ProgressButton.Position(3)=340;
                app.ProgressLabel.Text = "Ready";
            end             
        end

        % Value changed function: CheckBox_Netw_M
        function CheckBox_Netw_MValueChanged(app, event)
            value = app.CheckBox_Netw_M.Value;
            if value == 1
                app.Checkbox_flip("CONC"); 
            else
                app.Checkbox_flip("RU"); 
            end
            if app.initiated_plotdata ==1
                %4)Plotting
                app.ProgressLabel.Text = "Plotting";
                app.ProgressButton.Position(3)=310;
                drawnow()
                app.WebAppPlot;
                
            %5) Finished
                app.ProgressButton.Position(3)=340;
                app.ProgressLabel.Text = "Ready";
            end             
        end

        % Value changed function: AllinformationCheckBox
        function AllinformationCheckBoxValueChanged(app, event)
            value = app.AllinformationCheckBox.Value;
            app.export_info = value;
        end

        % Button pushed function: RerunButton
        function RerunButtonPushed(app, event)
            
            %app.update_rateconstants;
            %1)
            app.setReceptor;
            app.ProgressLabel.Text = "Initiating";
            app.ProgressButton.Position(3)=30;
                app.initiated_plotdata = 1;
                app.copy_all_parameters_from_default;
            %2)
            app.ProgressLabel.Text = "Effective concentration calculation";
            app.ProgressButton.Position(3)=30;
            drawnow()
            if app.PDF_updated == 1
                EffC_Calculator_polar(app)
                effC_name_converter(app);
            end
                
            %3)COncentration
            app.ProgressLabel.Text = "Calculateing curves";
            app.ProgressButton.Position(3)=200;
            drawnow()
                
                app.Output_concentraion = GUI_Manual_input3x3x3(app);
                
            %4)Plotting
            app.ProgressLabel.Text = "Plotting";
            app.ProgressButton.Position(3)=310;
            drawnow()
                app.PDF_plot_curves("", 0)
                app.WebAppPlot;
                
            %5) Finished
            app.ProgressButton.Position(3)=340;
            app.ProgressLabel.Text = "Ready";
            %save("Parameters", "app")
            app.rerunable = 1;
            app.PDF_updated =0;
            app.RerunButton.Enable = true;
            app.UITable.Data = rows2vars(struct2table(app.Parameters.EffCs));
        end

        % Value changed function: ReceptorConcentration_Receptor_4
        function ReceptorConcentration_Receptor_4ValueChanged(app, event)
            value = app.ReceptorConcentration_Receptor_4.Value;
            app.Parameters.SPR.baseR0 = value;
            app.Parameters.SPR.baseRU = (app.Parameters.SPR.baseR0*app.Parameters.constants.Volume * 1000 * app.Parameters.Receptor(1).MolecularWeight ) / app.Parameters.constants.RU2g; 
            app.RU_Receptor.Value = app.Parameters.SPR.baseRU;
            app.ReceptorConcentration_Receptor_4.Value = app.Parameters.SPR.baseR0;


        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            %newDisplayData = app.UITable.DisplayData;


             if app.initiated_plotdata == 1
                if app.export_info == 1   
%                     temp_data = transpose(table2cell(app.UITable.Data(:,2)));
%                     [app.Parameters.Effective_concentrations.EffC.Value] = temp_data{:};
%                     effC_name_converter(app);
%                     %app.UITable.Data = struct2table(app.Parameters.Effective_concentrations.EffC);
%                     %app.UITable.ColumnName = struct2table(app.Parameters.Effective_concentrations.EffC).Properties.VariableNames;
                else
                    
                    temp_table = struct2table(app.Parameters.EffCs);
                    temp_table.Variables = transpose(table2array(app.UITable.Data(:,2)));
                    app.Parameters.EffCs = table2struct(temp_table);
                    %effC_name_converter(app);
                    
                end
            end

            
        end

        % Callback function
        function resolutionAEditFieldValueChanged(app, event)
            value = app.resolutionAEditField.Value;
            if 1/value <0.1
                app.resolutionAEditField.Value = 1;
            else 
                app.Parameters.base.resolution=1/value;
            end
        end

        % Value changed function: LigandPDF_Distance_input
        function LigandPDF_Distance_inputValueChanged(app, event)
            %value = app.LigandPDF_Distance_input.Value;            
            %app.Parameters.PDF_input.Ligand_x = value;

        end

        % Value changed function: LigandPDF_Values_input
        function LigandPDF_Values_inputValueChanged(app, event)
            %value = app.LigandPDF_Values_input.Value;
            %app.Parameters.PDF_input.Ligand_y = value;

        end

        % Value changed function: PDF_Cumulative
        function PDF_CumulativeValueChanged(app, event)
            value = app.PDF_Cumulative.Value;
            app.Parameters.PDF_input.Cumulative = value;
            app.PDF_plot_curves("", 0);
        end

        % Value changed function: LigandPDF_rank
        function LigandPDF_rankValueChanged(app, event)
            value = str2num(app.LigandPDF_rank.Value);
            try 
                app.Parameters.Ligand(value).Valency;
                app.Parameters.PDF_input.Ligand_rank = value;
            catch
                app.LigandPDF_rank.Value = '1';
            end
            app.LigandPDF_step.Value = '1';
            app.Parameters.PDF_input.Ligand_step = '1';
            app.PDF_plot_curves("Ligand", 0)
        end

        % Value changed function: LigandPDF_step
        function LigandPDF_stepValueChanged(app, event)
            value = app.LigandPDF_step.Value;
            lignad_i = app.Parameters.PDF_input.Ligand_rank;
            
            if app.Parameters.Ligand(lignad_i).Valency == 1
                app.LigandPDF_step.Value = '1';
            elseif app.Parameters.Ligand(lignad_i).Valency == 2
                app.LigandPDF_step.Value = num2str(min(2, str2num(value)));
            end

            app.Parameters.PDF_input.Ligand_step = app.LigandPDF_step.Value;
            
            switch   str2num(value)
                case 1
                    app.Parameters.PDF_input.Ligand_from = 1; 
                    app.Parameters.PDF_input.Ligand_to   = 2;
                case 2
                    app.Parameters.PDF_input.Ligand_from = 1; 
                    app.Parameters.PDF_input.Ligand_to   = 2;
                case 3
                    app.Parameters.PDF_input.Ligand_from = 2; 
                    app.Parameters.PDF_input.Ligand_to   = 3;
                case 4              
                    app.Parameters.PDF_input.Ligand_from = 1; 
                    app.Parameters.PDF_input.Ligand_to   = 3;                
            end
            
            app.PDF_plot_curves("Ligand", 0)
        end

        % Value changed function: ReceptorPDF_step
        function ReceptorPDF_stepValueChanged(app, event)
            value = app.ReceptorPDF_step.Value;
            
            if app.Parameters.Receptor.Valency == 1
                app.ReceptorPDF_step.Value = '1';
            elseif app.Parameters.Receptor.Valency == 2
                app.ReceptorPDF_step.Value = num2str(min(2, str2num(value)));
            end
            
            app.Parameters.PDF_input.Receptor_step = app.ReceptorPDF_step.Value;
            
            switch str2num(value)
                case 1
                    app.Parameters.PDF_input.Receptor_from = 1; 
                    app.Parameters.PDF_input.Receptor_to   = 2;
                case 2
                    app.Parameters.PDF_input.Receptor_from = 1; 
                    app.Parameters.PDF_input.Receptor_to   = 2;
                case 3
                    app.Parameters.PDF_input.Receptor_from = 2; 
                    app.Parameters.PDF_input.Receptor_to   = 3;
                case 4              
                    app.Parameters.PDF_input.Receptor_from = 1; 
                    app.Parameters.PDF_input.Receptor_to   = 3;                
            end
                        
            app.PDF_plot_curves("Receptor", 0)
        end

        % Value changed function: ReceptorPDF_Distance_input
        function ReceptorPDF_Distance_inputValueChanged(app, event)
            %value = app.ReceptorPDF_Distance_input.Value;
            %app.Parameters.PDF_input.Receptor_x = value;

        end

        % Value changed function: ReceptorPDF_values_input
        function ReceptorPDF_values_inputValueChanged(app, event)
            %value = app.ReceptorPDF_values_input.Value;
            %app.Parameters.PDF_input.Receptor_y = value;

        end

        % Button pushed function: LigandPDF_Update
        function LigandPDF_UpdateButtonPushed(app, event)
            x = cellfun(@str2num,app.LigandPDF_Distance_input.Value);
            y = cellfun(@str2num,app.LigandPDF_Values_input.Value);
            x = [x;max(x)*1.001;1e10];
            y = [y;0;0];
            app.Parameters.PDF_input.Ligand_x = x;
            app.Parameters.PDF_input.Ligand_y = app.PDF_normalise(y,x);
            app.PDF_plot_curves("Ligand", 1)
        end

        % Button pushed function: ReceptorPDF_Update
        function ReceptorPDF_UpdateButtonPushed(app, event)
            x = cellfun(@str2num, app.ReceptorPDF_Distance_input.Value);
            y = cellfun(@str2num,app.ReceptorPDF_values_input.Value);
            x = [x;max(x)*1.001;1e10];
            y = [y;0;0];
            
            app.Parameters.PDF_input.Receptor_x = x;
            app.Parameters.PDF_input.Receptor_y = app.PDF_normalise(y,x);

            app.PDF_plot_curves("Receptor", 1)
        end

        % Value changed function: Copy_Values_A
        function Copy_Values_AValueChanged(app, event)
            value = app.Copy_Values_A.Value;
            if value == true
            copy_value_on =  app.Kon_LigandA_R1_L1.Value; 
            app.Kon_LigandA_R1_L1.Value = copy_value_on;
            app.Kon_LigandA_R2_L1.Value = copy_value_on;
            app.Kon_LigandA_R3_L1.Value = copy_value_on;
            app.Kon_LigandA_R1_L2.Value = copy_value_on;
            app.Kon_LigandA_R2_L2.Value = copy_value_on;
            app.Kon_LigandA_R3_L2.Value = copy_value_on;
            app.Kon_LigandA_R1_L3.Value = copy_value_on;
            app.Kon_LigandA_R2_L3.Value = copy_value_on;
            app.Kon_LigandA_R3_L3.Value = copy_value_on;
            
            copy_value_off =  app.Koff_LigandA_R1_L1.Value; 
            app.Koff_LigandA_R1_L1.Value = copy_value_off;
            app.Koff_LigandA_R2_L1.Value = copy_value_off;
            app.Koff_LigandA_R3_L1.Value = copy_value_off;
            app.Koff_LigandA_R1_L2.Value = copy_value_off;
            app.Koff_LigandA_R2_L2.Value = copy_value_off;
            app.Koff_LigandA_R3_L2.Value = copy_value_off;
            app.Koff_LigandA_R1_L3.Value = copy_value_off;
            app.Koff_LigandA_R2_L3.Value = copy_value_off;
            app.Koff_LigandA_R3_L3.Value = copy_value_off;
            drawnow;
            end
        end

        % Value changed function: Copy_Values_B
        function Copy_Values_BValueChanged(app, event)
            value = app.Copy_Values_B.Value;
            if value == true
            copy_value_on =  app.Kon_LigandB_R1_L1.Value; 
            app.Kon_LigandB_R1_L1.Value = copy_value_on;
            app.Kon_LigandB_R2_L1.Value = copy_value_on;
            app.Kon_LigandB_R3_L1.Value = copy_value_on;
            app.Kon_LigandB_R1_L2.Value = copy_value_on;
            app.Kon_LigandB_R2_L2.Value = copy_value_on;
            app.Kon_LigandB_R3_L2.Value = copy_value_on;
            app.Kon_LigandB_R1_L3.Value = copy_value_on;
            app.Kon_LigandB_R2_L3.Value = copy_value_on;
            app.Kon_LigandB_R3_L3.Value = copy_value_on;
            
            copy_value_off =  app.Koff_LigandB_R1_L1.Value; 
            app.Koff_LigandB_R1_L1.Value = copy_value_off;
            app.Koff_LigandB_R2_L1.Value = copy_value_off;
            app.Koff_LigandB_R3_L1.Value = copy_value_off;
            app.Koff_LigandB_R1_L2.Value = copy_value_off;
            app.Koff_LigandB_R2_L2.Value = copy_value_off;
            app.Koff_LigandB_R3_L2.Value = copy_value_off;
            app.Koff_LigandB_R1_L3.Value = copy_value_off;
            app.Koff_LigandB_R2_L3.Value = copy_value_off;
            app.Koff_LigandB_R3_L3.Value = copy_value_off;
            drawnow;
            end
        end

        % Value changed function: Copy_Values_C
        function Copy_Values_CValueChanged(app, event)
            value = app.Copy_Values_C.Value;
            if value == true
            copy_value_on =  app.Kon_LigandC_R1_L1.Value; 
            app.Kon_LigandC_R1_L1.Value = copy_value_on;
            app.Kon_LigandC_R2_L1.Value = copy_value_on;
            app.Kon_LigandC_R3_L1.Value = copy_value_on;
            app.Kon_LigandC_R1_L2.Value = copy_value_on;
            app.Kon_LigandC_R2_L2.Value = copy_value_on;
            app.Kon_LigandC_R3_L2.Value = copy_value_on;
            app.Kon_LigandC_R1_L3.Value = copy_value_on;
            app.Kon_LigandC_R2_L3.Value = copy_value_on;
            app.Kon_LigandC_R3_L3.Value = copy_value_on;
            
            copy_value_off =  app.Koff_LigandC_R1_L1.Value; 
            app.Koff_LigandC_R1_L1.Value = copy_value_off;
            app.Koff_LigandC_R2_L1.Value = copy_value_off;
            app.Koff_LigandC_R3_L1.Value = copy_value_off;
            app.Koff_LigandC_R1_L2.Value = copy_value_off;
            app.Koff_LigandC_R2_L2.Value = copy_value_off;
            app.Koff_LigandC_R3_L2.Value = copy_value_off;
            app.Koff_LigandC_R1_L3.Value = copy_value_off;
            app.Koff_LigandC_R2_L3.Value = copy_value_off;
            app.Koff_LigandC_R3_L3.Value = copy_value_off;
            drawnow;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup2 = uitabgroup(app.LeftPanel);
            app.TopologyTab = uitab(app.TabGroup2);
            app.TopologyTab.Title = 'Topology';
            app.Image_Ligand_3 = uiimage(app.TopologyTab);
            app.Image_Ligand_3.ImageSource = 'Tab1_background.png';
            app.Receptor_linker_1 = uiimage(app.TopologyTab);
            app.Receptor_linker_1.ImageSource = 'Linker_receptor.png';
            app.Receptor_linker_2 = uiimage(app.TopologyTab);
            app.Receptor_linker_2.ImageSource = 'Empty.png';
            app.TitleImage_LigandA = uiimage(app.TopologyTab);
            app.TitleImage_LigandA.ImageSource = 'Ligand_A_0.png';
            app.TitleImage_LigandB = uiimage(app.TopologyTab);
            app.TitleImage_LigandB.ImageSource = 'Empty.png';
            app.TitleImage_LigandC = uiimage(app.TopologyTab);
            app.TitleImage_LigandC.ImageClickedFcn = createCallbackFcn(app, @TitleImage_LigandCClicked, true);
            app.TitleImage_LigandC.ImageSource = 'Empty.png';
            app.TitleImage_Receptor = uiimage(app.TopologyTab);
            app.TitleImage_Receptor.ImageSource = 'Receptor_0.png';
            app.iButton_LigandA_1 = uiimage(app.TopologyTab);
            app.iButton_LigandA_1.ImageClickedFcn = createCallbackFcn(app, @iButton_LigandA_1ImageClicked, true);
            app.iButton_LigandA_1.ImageSource = 'Ligand_A_1.png';
            app.iButton_LigandA_2 = uiimage(app.TopologyTab);
            app.iButton_LigandA_2.ImageClickedFcn = createCallbackFcn(app, @iButton_LigandA_2ImageClicked, true);
            app.iButton_LigandA_2.ImageSource = 'Ligand_A_1.png';
            app.iButton_LigandA_3 = uiimage(app.TopologyTab);
            app.iButton_LigandA_3.ImageClickedFcn = createCallbackFcn(app, @iButton_LigandA_3ImageClicked, true);
            app.iButton_LigandA_3.ImageSource = 'Empty.png';
            app.iButton_LigandB_1 = uiimage(app.TopologyTab);
            app.iButton_LigandB_1.ImageClickedFcn = createCallbackFcn(app, @iButton_LigandB_1ImageClicked, true);
            app.iButton_LigandB_1.ImageSource = 'Empty.png';
            app.iButton_LigandB_2 = uiimage(app.TopologyTab);
            app.iButton_LigandB_2.ImageClickedFcn = createCallbackFcn(app, @iButton_LigandB_2ImageClicked, true);
            app.iButton_LigandB_2.ImageSource = 'Empty.png';
            app.iButton_LigandB_3 = uiimage(app.TopologyTab);
            app.iButton_LigandB_3.ImageClickedFcn = createCallbackFcn(app, @iButton_LigandB_3ImageClicked, true);
            app.iButton_LigandB_3.ImageSource = 'Empty.png';
            app.iButton_LigandC_1 = uiimage(app.TopologyTab);
            app.iButton_LigandC_1.ImageClickedFcn = createCallbackFcn(app, @iButton_LigandC_1ImageClicked, true);
            app.iButton_LigandC_1.ImageSource = 'Empty.png';
            app.iButton_LigandC_2 = uiimage(app.TopologyTab);
            app.iButton_LigandC_2.ImageClickedFcn = createCallbackFcn(app, @iButton_LigandC_2ImageClicked, true);
            app.iButton_LigandC_2.ImageSource = 'Empty.png';
            app.iButton_LigandC_3 = uiimage(app.TopologyTab);
            app.iButton_LigandC_3.ImageClickedFcn = createCallbackFcn(app, @iButton_LigandC_3ImageClicked, true);
            app.iButton_LigandC_3.ImageSource = 'Empty.png';
            app.iButton_Receptor_1 = uiimage(app.TopologyTab);
            app.iButton_Receptor_1.ImageClickedFcn = createCallbackFcn(app, @iButton_Receptor_1ImageClicked, true);
            app.iButton_Receptor_1.ImageSource = 'Receptor.png';
            app.iButton_Receptor_2 = uiimage(app.TopologyTab);
            app.iButton_Receptor_2.ImageClickedFcn = createCallbackFcn(app, @iButton_Receptor_2ImageClicked, true);
            app.iButton_Receptor_2.ImageSource = 'Receptor.png';
            app.iButton_Receptor_3 = uiimage(app.TopologyTab);
            app.iButton_Receptor_3.ImageClickedFcn = createCallbackFcn(app, @iButton_Receptor_3ImageClicked, true);
            app.iButton_Receptor_3.ImageSource = 'Empty.png';
            app.Image_linker_LigandA_1 = uiimage(app.TopologyTab);
            app.Image_linker_LigandA_1.ImageSource = 'Linker_ligand.png';
            app.Image_linker_LigandA_2 = uiimage(app.TopologyTab);
            app.Image_linker_LigandA_2.ImageSource = 'Empty.png';
            app.Image_linker_LigandC_1 = uiimage(app.TopologyTab);
            app.Image_linker_LigandC_1.ImageSource = 'Empty.png';
            app.Image_linker_LigandC_2 = uiimage(app.TopologyTab);
            app.Image_linker_LigandC_2.ImageSource = 'Empty.png';
            app.Image_linker_LigandB_1 = uiimage(app.TopologyTab);
            app.Image_linker_LigandB_1.ImageSource = 'Empty.png';
            app.Image_linker_LigandB_2 = uiimage(app.TopologyTab);
            app.Image_linker_LigandB_2.ImageSource = 'Empty.png';
            app.LigandATab = uitab(app.TabGroup2);
            app.LigandATab.Title = 'Ligand A';
            app.Image_Tab_LigandA = uiimage(app.LigandATab);
            app.Image_Tab_LigandA.ImageSource = 'MK_Tab2_A1x1.png';
            app.Panel = uipanel(app.LigandATab);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.Kon_LigandA_R1_L1 = uieditfield(app.GridLayout2, 'numeric');
            app.Kon_LigandA_R1_L1.ValueChangedFcn = createCallbackFcn(app, @Kon_LigandA_R1_L1ValueChanged, true);
            app.Kon_LigandA_R1_L1.Tooltip = {'Marked association rate constant (1/Ms)'};
            app.Kon_LigandA_R1_L1.Value = 1000000;
            app.Koff_LigandA_R1_L1 = uieditfield(app.GridLayout2, 'numeric');
            app.Koff_LigandA_R1_L1.ValueChangedFcn = createCallbackFcn(app, @Koff_LigandA_R1_L1ValueChanged, true);
            app.Koff_LigandA_R1_L1.Tooltip = {'Marked dissociation rate constant (1/s)'};
            app.Koff_LigandA_R1_L1.Value = 1;
            app.Kon_LigandA_R2_L1 = uieditfield(app.GridLayout2, 'numeric');
            app.Kon_LigandA_R2_L1.ValueChangedFcn = createCallbackFcn(app, @Kon_LigandA_R2_L1ValueChanged, true);
            app.Kon_LigandA_R2_L1.Tooltip = {'Marked association rate constant (1/Ms)'};
            app.Kon_LigandA_R2_L1.Value = 1000000;
            app.Koff_LigandA_R2_L1 = uieditfield(app.GridLayout2, 'numeric');
            app.Koff_LigandA_R2_L1.ValueChangedFcn = createCallbackFcn(app, @Koff_LigandA_R2_L1ValueChanged, true);
            app.Koff_LigandA_R2_L1.Tooltip = {'Marked dissociation rate constant (1/s)'};
            app.Koff_LigandA_R2_L1.Value = 1;
            app.Kon_LigandA_R3_L1 = uieditfield(app.GridLayout2, 'numeric');
            app.Kon_LigandA_R3_L1.ValueChangedFcn = createCallbackFcn(app, @Kon_LigandA_R3_L1ValueChanged, true);
            app.Kon_LigandA_R3_L1.Tooltip = {'Marked association rate constant (1/Ms)'};
            app.Kon_LigandA_R3_L1.Value = 1000000;
            app.Koff_LigandA_R3_L1 = uieditfield(app.GridLayout2, 'numeric');
            app.Koff_LigandA_R3_L1.ValueChangedFcn = createCallbackFcn(app, @Koff_LigandA_R3_L1ValueChanged, true);
            app.Koff_LigandA_R3_L1.Tooltip = {'Marked dissociation rate constant (1/s)'};
            app.Koff_LigandA_R3_L1.Value = 1;
            app.Panel_2 = uipanel(app.LigandATab);
            app.GridLayout2_2 = uigridlayout(app.Panel_2);
            app.Kon_LigandA_R1_L2 = uieditfield(app.GridLayout2_2, 'numeric');
            app.Kon_LigandA_R1_L2.ValueChangedFcn = createCallbackFcn(app, @Kon_LigandA_R1_L2ValueChanged, true);
            app.Kon_LigandA_R1_L2.Tooltip = {'Marked association rate constant (1/Ms)'};
            app.Kon_LigandA_R1_L2.Value = 1000000;
            app.Koff_LigandA_R1_L2 = uieditfield(app.GridLayout2_2, 'numeric');
            app.Koff_LigandA_R1_L2.ValueChangedFcn = createCallbackFcn(app, @Koff_LigandA_R1_L2ValueChanged, true);
            app.Koff_LigandA_R1_L2.Tooltip = {'Marked dissociation rate constant (1/s)'};
            app.Koff_LigandA_R1_L2.Value = 1;
            app.Kon_LigandA_R2_L2 = uieditfield(app.GridLayout2_2, 'numeric');
            app.Kon_LigandA_R2_L2.ValueChangedFcn = createCallbackFcn(app, @Kon_LigandA_R2_L2ValueChanged, true);
            app.Kon_LigandA_R2_L2.Tooltip = {'Marked association rate constant (1/Ms)'};
            app.Kon_LigandA_R2_L2.Value = 1000000;
            app.Koff_LigandA_R2_L2 = uieditfield(app.GridLayout2_2, 'numeric');
            app.Koff_LigandA_R2_L2.ValueChangedFcn = createCallbackFcn(app, @Koff_LigandA_R2_L2ValueChanged, true);
            app.Koff_LigandA_R2_L2.Tooltip = {'Marked dissociation rate constant (1/s)'};
            app.Koff_LigandA_R2_L2.Value = 1;
            app.Kon_LigandA_R3_L2 = uieditfield(app.GridLayout2_2, 'numeric');
            app.Kon_LigandA_R3_L2.ValueChangedFcn = createCallbackFcn(app, @Kon_LigandA_R3_L2ValueChanged, true);
            app.Kon_LigandA_R3_L2.Tooltip = {'Marked association rate constant (1/Ms)'};
            app.Kon_LigandA_R3_L2.Value = 1000000;
            app.Koff_LigandA_R3_L2 = uieditfield(app.GridLayout2_2, 'numeric');
            app.Koff_LigandA_R3_L2.ValueChangedFcn = createCallbackFcn(app, @Koff_LigandA_R3_L2ValueChanged, true);
            app.Koff_LigandA_R3_L2.Tooltip = {'Marked dissociation rate constant (1/s)'};
            app.Koff_LigandA_R3_L2.Value = 1;
            app.Panel_3 = uipanel(app.LigandATab);
            app.GridLayout2_3 = uigridlayout(app.Panel_3);
            app.Kon_LigandA_R1_L3 = uieditfield(app.GridLayout2_3, 'numeric');
            app.Kon_LigandA_R1_L3.ValueChangedFcn = createCallbackFcn(app, @Kon_LigandA_R1_L3ValueChanged, true);
            app.Kon_LigandA_R1_L3.Tooltip = {'Marked association rate constant (1/Ms)'};
            app.Kon_LigandA_R1_L3.Value = 1000000;
            app.Koff_LigandA_R1_L3 = uieditfield(app.GridLayout2_3, 'numeric');
            app.Koff_LigandA_R1_L3.ValueChangedFcn = createCallbackFcn(app, @Koff_LigandA_R1_L3ValueChanged, true);
            app.Koff_LigandA_R1_L3.Tooltip = {'Marked dissociation rate constant (1/s)'};
            app.Koff_LigandA_R1_L3.Value = 1;
            app.Kon_LigandA_R2_L3 = uieditfield(app.GridLayout2_3, 'numeric');
            app.Kon_LigandA_R2_L3.ValueChangedFcn = createCallbackFcn(app, @Kon_LigandA_R2_L3ValueChanged, true);
            app.Kon_LigandA_R2_L3.Tooltip = {'Marked association rate constant (1/Ms)'};
            app.Kon_LigandA_R2_L3.Value = 1000000;
            app.Koff_LigandA_R2_L3 = uieditfield(app.GridLayout2_3, 'numeric');
            app.Koff_LigandA_R2_L3.ValueChangedFcn = createCallbackFcn(app, @Koff_LigandA_R2_L3ValueChanged, true);
            app.Koff_LigandA_R2_L3.Tooltip = {'Marked dissociation rate constant (1/s)'};
            app.Koff_LigandA_R2_L3.Value = 1;
            app.Kon_LigandA_R3_L3 = uieditfield(app.GridLayout2_3, 'numeric');
            app.Kon_LigandA_R3_L3.ValueChangedFcn = createCallbackFcn(app, @Kon_LigandA_R3_L3ValueChanged, true);
            app.Kon_LigandA_R3_L3.Tooltip = {'Marked association rate constant (1/Ms)'};
            app.Kon_LigandA_R3_L3.Value = 1000000;
            app.Koff_LigandA_R3_L3 = uieditfield(app.GridLayout2_3, 'numeric');
            app.Koff_LigandA_R3_L3.ValueChangedFcn = createCallbackFcn(app, @Koff_LigandA_R3_L3ValueChanged, true);
            app.Koff_LigandA_R3_L3.Tooltip = {'Marked dissociation rate constant (1/s)'};
            app.Koff_LigandA_R3_L3.Value = 1;
            app.Copy_Values_A = uicheckbox(app.LigandATab);
            app.Copy_Values_A.ValueChangedFcn = createCallbackFcn(app, @Copy_Values_AValueChanged, true);
            app.Copy_Values_A.Tooltip = {'Copy the values of the first association and dissociation rate constants to all other cells'};
            app.Copy_Values_A.Text = ' ';
            app.EditFieldLabel = uilabel(app.LigandATab);
            app.EditFieldLabel.Tooltip = {'Molecular weight of Ligand A in g/mol'};
            app.EditFieldLabel.Text = ' ';
            app.MolecularWeight_LA = uieditfield(app.LigandATab, 'numeric');
            app.MolecularWeight_LA.ValueChangedFcn = createCallbackFcn(app, @MolecularWeight_LAValueChanged, true);
            app.MolecularWeight_LA.Tooltip = {'Molecular weight of Ligand A in g/mol'};
            app.MolecularWeight_LA.Value = 10000;
            app.EditField_2Label = uilabel(app.LigandATab);
            app.EditField_2Label.Text = ' ';
            app.UnitDiameter1_LA = uieditfield(app.LigandATab, 'numeric');
            app.UnitDiameter1_LA.ValueChangedFcn = createCallbackFcn(app, @UnitDiameter1_LAValueChanged, true);
            app.UnitDiameter1_LA.Tooltip = {'First Ligand-unit diameter (A) '};
            app.UnitDiameter1_LA.Value = 10;
            app.EditField_3Label = uilabel(app.LigandATab);
            app.EditField_3Label.Text = ' ';
            app.UnitDiameter2_LA = uieditfield(app.LigandATab, 'numeric');
            app.UnitDiameter2_LA.ValueChangedFcn = createCallbackFcn(app, @UnitDiameter2_LAValueChanged, true);
            app.UnitDiameter2_LA.Tooltip = {'Second Ligand-unit diameter (A) '};
            app.UnitDiameter2_LA.Value = 10;
            app.EditField_4Label = uilabel(app.LigandATab);
            app.EditField_4Label.Text = ' ';
            app.UnitDiameter3_LA = uieditfield(app.LigandATab, 'numeric');
            app.UnitDiameter3_LA.ValueChangedFcn = createCallbackFcn(app, @UnitDiameter3_LAValueChanged, true);
            app.UnitDiameter3_LA.Tooltip = {'Third Ligand-unit diameter (A)'};
            app.UnitDiameter3_LA.Value = 10;
            app.LinkerLength1_LA = uieditfield(app.LigandATab, 'numeric');
            app.LinkerLength1_LA.ValueChangedFcn = createCallbackFcn(app, @LinkerLength1_LAValueChanged, true);
            app.LinkerLength1_LA.Tooltip = {'First linker contour length (A)'};
            app.LinkerLength1_LA.Value = 5;
            app.LinkerLength2_LA = uieditfield(app.LigandATab, 'numeric');
            app.LinkerLength2_LA.ValueChangedFcn = createCallbackFcn(app, @LinkerLength2_LAValueChanged, true);
            app.LinkerLength2_LA.Tooltip = {'Second linker contour length (A)'};
            app.LinkerLength2_LA.Value = 5;
            app.EditField_7Label = uilabel(app.LigandATab);
            app.EditField_7Label.Text = ' ';
            app.PersistanceLength1_LA = uieditfield(app.LigandATab, 'numeric');
            app.PersistanceLength1_LA.ValueChangedFcn = createCallbackFcn(app, @PersistanceLength1_LAValueChanged, true);
            app.PersistanceLength1_LA.Tooltip = {'First linker persistence length (A)'};
            app.PersistanceLength1_LA.Value = 5;
            app.EditField_8Label = uilabel(app.LigandATab);
            app.EditField_8Label.Text = ' ';
            app.PersistanceLength2_LA = uieditfield(app.LigandATab, 'numeric');
            app.PersistanceLength2_LA.ValueChangedFcn = createCallbackFcn(app, @PersistanceLength2_LAValueChanged, true);
            app.PersistanceLength2_LA.Tooltip = {'Second linker contour length (A)'};
            app.PersistanceLength2_LA.Value = 5;
            app.MWLabel = uilabel(app.LigandATab);
            app.MWLabel.Tooltip = {'Molecular weight of Ligand A in g/mol'};
            app.MWLabel.Text = 'MW';
            app.LigandBTab = uitab(app.TabGroup2);
            app.LigandBTab.Title = 'Ligand B';
            app.Image_Tab_LigandB = uiimage(app.LigandBTab);
            app.Image_Tab_LigandB.ImageSource = 'MK_Tab2_B1x1.png';
            app.Panel_4 = uipanel(app.LigandBTab);
            app.GridLayout2_4 = uigridlayout(app.Panel_4);
            app.Kon_LigandB_R1_L1 = uieditfield(app.GridLayout2_4, 'numeric');
            app.Kon_LigandB_R1_L1.ValueChangedFcn = createCallbackFcn(app, @Kon_LigandB_R1_L1ValueChanged, true);
            app.Kon_LigandB_R1_L1.Tooltip = {'Marked association rate constant (1/Ms)'};
            app.Kon_LigandB_R1_L1.Value = 1000000;
            app.Koff_LigandB_R1_L1 = uieditfield(app.GridLayout2_4, 'numeric');
            app.Koff_LigandB_R1_L1.ValueChangedFcn = createCallbackFcn(app, @Koff_LigandB_R1_L1ValueChanged, true);
            app.Koff_LigandB_R1_L1.Tooltip = {'Marked dissociation rate constant (1/s)'};
            app.Koff_LigandB_R1_L1.Value = 1;
            app.Kon_LigandB_R2_L1 = uieditfield(app.GridLayout2_4, 'numeric');
            app.Kon_LigandB_R2_L1.ValueChangedFcn = createCallbackFcn(app, @Kon_LigandB_R2_L1ValueChanged, true);
            app.Kon_LigandB_R2_L1.Tooltip = {'Marked association rate constant (1/Ms)'};
            app.Kon_LigandB_R2_L1.Value = 1000000;
            app.Koff_LigandB_R2_L1 = uieditfield(app.GridLayout2_4, 'numeric');
            app.Koff_LigandB_R2_L1.ValueChangedFcn = createCallbackFcn(app, @Koff_LigandB_R2_L1ValueChanged, true);
            app.Koff_LigandB_R2_L1.Tooltip = {'Marked dissociation rate constant (1/s)'};
            app.Koff_LigandB_R2_L1.Value = 1;
            app.Kon_LigandB_R3_L1 = uieditfield(app.GridLayout2_4, 'numeric');
            app.Kon_LigandB_R3_L1.ValueChangedFcn = createCallbackFcn(app, @Kon_LigandB_R3_L1ValueChanged, true);
            app.Kon_LigandB_R3_L1.Tooltip = {'Marked association rate constant (1/Ms)'};
            app.Kon_LigandB_R3_L1.Value = 1000000;
            app.Koff_LigandB_R3_L1 = uieditfield(app.GridLayout2_4, 'numeric');
            app.Koff_LigandB_R3_L1.ValueChangedFcn = createCallbackFcn(app, @Koff_LigandB_R3_L1ValueChanged, true);
            app.Koff_LigandB_R3_L1.Tooltip = {'Marked dissociation rate constant (1/s)'};
            app.Koff_LigandB_R3_L1.Value = 1;
            app.Panel_5 = uipanel(app.LigandBTab);
            app.GridLayout2_5 = uigridlayout(app.Panel_5);
            app.Kon_LigandB_R1_L2 = uieditfield(app.GridLayout2_5, 'numeric');
            app.Kon_LigandB_R1_L2.ValueChangedFcn = createCallbackFcn(app, @Kon_LigandB_R1_L2ValueChanged, true);
            app.Kon_LigandB_R1_L2.Tooltip = {'Marked association rate constant (1/Ms)'};
            app.Kon_LigandB_R1_L2.Value = 1000000;
            app.Koff_LigandB_R1_L2 = uieditfield(app.GridLayout2_5, 'numeric');
            app.Koff_LigandB_R1_L2.ValueChangedFcn = createCallbackFcn(app, @Koff_LigandB_R1_L2ValueChanged, true);
            app.Koff_LigandB_R1_L2.Tooltip = {'Marked dissociation rate constant (1/s)'};
            app.Koff_LigandB_R1_L2.Value = 1;
            app.Kon_LigandB_R2_L2 = uieditfield(app.GridLayout2_5, 'numeric');
            app.Kon_LigandB_R2_L2.ValueChangedFcn = createCallbackFcn(app, @Kon_LigandB_R2_L2ValueChanged, true);
            app.Kon_LigandB_R2_L2.Tooltip = {'Marked association rate constant (1/Ms)'};
            app.Kon_LigandB_R2_L2.Value = 1000000;
            app.Koff_LigandB_R2_L2 = uieditfield(app.GridLayout2_5, 'numeric');
            app.Koff_LigandB_R2_L2.ValueChangedFcn = createCallbackFcn(app, @Koff_LigandB_R2_L2ValueChanged, true);
            app.Koff_LigandB_R2_L2.Tooltip = {'Marked dissociation rate constant (1/s)'};
            app.Koff_LigandB_R2_L2.Value = 1;
            app.Kon_LigandB_R3_L2 = uieditfield(app.GridLayout2_5, 'numeric');
            app.Kon_LigandB_R3_L2.ValueChangedFcn = createCallbackFcn(app, @Kon_LigandB_R3_L2ValueChanged, true);
            app.Kon_LigandB_R3_L2.Tooltip = {'Marked association rate constant (1/Ms)'};
            app.Kon_LigandB_R3_L2.Value = 1000000;
            app.Koff_LigandB_R3_L2 = uieditfield(app.GridLayout2_5, 'numeric');
            app.Koff_LigandB_R3_L2.ValueChangedFcn = createCallbackFcn(app, @Koff_LigandB_R3_L2ValueChanged, true);
            app.Koff_LigandB_R3_L2.Tooltip = {'Marked dissociation rate constant (1/s)'};
            app.Koff_LigandB_R3_L2.Value = 1;
            app.Panel_6 = uipanel(app.LigandBTab);
            app.GridLayout2_6 = uigridlayout(app.Panel_6);
            app.Kon_LigandB_R1_L3 = uieditfield(app.GridLayout2_6, 'numeric');
            app.Kon_LigandB_R1_L3.ValueChangedFcn = createCallbackFcn(app, @Kon_LigandB_R1_L3ValueChanged, true);
            app.Kon_LigandB_R1_L3.Tooltip = {'Marked association rate constant (1/Ms)'};
            app.Kon_LigandB_R1_L3.Value = 1000000;
            app.Koff_LigandB_R1_L3 = uieditfield(app.GridLayout2_6, 'numeric');
            app.Koff_LigandB_R1_L3.ValueChangedFcn = createCallbackFcn(app, @Koff_LigandB_R1_L3ValueChanged, true);
            app.Koff_LigandB_R1_L3.Tooltip = {'Marked dissociation rate constant (1/s)'};
            app.Koff_LigandB_R1_L3.Value = 1;
            app.Kon_LigandB_R2_L3 = uieditfield(app.GridLayout2_6, 'numeric');
            app.Kon_LigandB_R2_L3.ValueChangedFcn = createCallbackFcn(app, @Kon_LigandB_R2_L3ValueChanged, true);
            app.Kon_LigandB_R2_L3.Tooltip = {'Marked association rate constant (1/Ms)'};
            app.Kon_LigandB_R2_L3.Value = 1000000;
            app.Koff_LigandB_R2_L3 = uieditfield(app.GridLayout2_6, 'numeric');
            app.Koff_LigandB_R2_L3.ValueChangedFcn = createCallbackFcn(app, @Koff_LigandB_R2_L3ValueChanged, true);
            app.Koff_LigandB_R2_L3.Tooltip = {'Marked dissociation rate constant (1/s)'};
            app.Koff_LigandB_R2_L3.Value = 1;
            app.Kon_LigandB_R3_L3 = uieditfield(app.GridLayout2_6, 'numeric');
            app.Kon_LigandB_R3_L3.ValueChangedFcn = createCallbackFcn(app, @Kon_LigandB_R3_L3ValueChanged, true);
            app.Kon_LigandB_R3_L3.Tooltip = {'Marked association rate constant (1/Ms)'};
            app.Kon_LigandB_R3_L3.Value = 1000000;
            app.Koff_LigandB_R3_L3 = uieditfield(app.GridLayout2_6, 'numeric');
            app.Koff_LigandB_R3_L3.ValueChangedFcn = createCallbackFcn(app, @Koff_LigandB_R3_L3ValueChanged, true);
            app.Koff_LigandB_R3_L3.Tooltip = {'Marked dissociation rate constant (1/s)'};
            app.Koff_LigandB_R3_L3.Value = 1;
            app.CheckBox_B = uicheckbox(app.LigandBTab);
            app.CheckBox_B.Text = ' ';
            app.LinkerLength1_LB = uieditfield(app.LigandBTab, 'numeric');
            app.LinkerLength1_LB.ValueChangedFcn = createCallbackFcn(app, @LinkerLength1_LBValueChanged, true);
            app.LinkerLength1_LB.Tooltip = {'First linker contour length (A)'};
            app.LinkerLength1_LB.Value = 5;
            app.LinkerLength2_LB = uieditfield(app.LigandBTab, 'numeric');
            app.LinkerLength2_LB.ValueChangedFcn = createCallbackFcn(app, @LinkerLength2_LBValueChanged, true);
            app.LinkerLength2_LB.Tooltip = {'Second linker contour length (A)'};
            app.LinkerLength2_LB.Value = 5;
            app.EditFieldLabel_2 = uilabel(app.LigandBTab);
            app.EditFieldLabel_2.Text = ' ';
            app.MolecularWeight_LB = uieditfield(app.LigandBTab, 'numeric');
            app.MolecularWeight_LB.ValueChangedFcn = createCallbackFcn(app, @MolecularWeight_LBValueChanged, true);
            app.MolecularWeight_LB.Tooltip = {'Molecular weight of Ligand B in g/mol'};
            app.MolecularWeight_LB.Value = 10000;
            app.EditField_2Label_2 = uilabel(app.LigandBTab);
            app.EditField_2Label_2.Text = ' ';
            app.UnitDiameter1_LB = uieditfield(app.LigandBTab, 'numeric');
            app.UnitDiameter1_LB.ValueChangedFcn = createCallbackFcn(app, @UnitDiameter1_LBValueChanged, true);
            app.UnitDiameter1_LB.Tooltip = {'First Ligand-unit diameter (A)'};
            app.UnitDiameter1_LB.Value = 10;
            app.EditField_3Label_2 = uilabel(app.LigandBTab);
            app.EditField_3Label_2.Text = ' ';
            app.UnitDiameter2_LB = uieditfield(app.LigandBTab, 'numeric');
            app.UnitDiameter2_LB.ValueChangedFcn = createCallbackFcn(app, @UnitDiameter2_LBValueChanged, true);
            app.UnitDiameter2_LB.Tooltip = {'Second Ligand-unit diameter (A)'};
            app.UnitDiameter2_LB.Value = 10;
            app.EditField_4Label_2 = uilabel(app.LigandBTab);
            app.EditField_4Label_2.Text = ' ';
            app.UnitDiameter3_LB = uieditfield(app.LigandBTab, 'numeric');
            app.UnitDiameter3_LB.ValueChangedFcn = createCallbackFcn(app, @UnitDiameter3_LBValueChanged, true);
            app.UnitDiameter3_LB.Tooltip = {'Third Ligand-unit diameter (A)'};
            app.UnitDiameter3_LB.Value = 10;
            app.EditField_7Label_2 = uilabel(app.LigandBTab);
            app.EditField_7Label_2.Text = ' ';
            app.PersistanceLength1_LB = uieditfield(app.LigandBTab, 'numeric');
            app.PersistanceLength1_LB.ValueChangedFcn = createCallbackFcn(app, @PersistanceLength1_LBValueChanged, true);
            app.PersistanceLength1_LB.Tooltip = {'First linker persistence length (A)'};
            app.PersistanceLength1_LB.Value = 5;
            app.EditField_8Label_2 = uilabel(app.LigandBTab);
            app.EditField_8Label_2.Text = ' ';
            app.PersistanceLength2_LB = uieditfield(app.LigandBTab, 'numeric');
            app.PersistanceLength2_LB.ValueChangedFcn = createCallbackFcn(app, @PersistanceLength2_LBValueChanged, true);
            app.PersistanceLength2_LB.Tooltip = {'Second linker persistence length (A)'};
            app.PersistanceLength2_LB.Value = 5;
            app.MWLabel_2 = uilabel(app.LigandBTab);
            app.MWLabel_2.Text = 'MW';
            app.Copy_Values_B = uicheckbox(app.LigandBTab);
            app.Copy_Values_B.ValueChangedFcn = createCallbackFcn(app, @Copy_Values_BValueChanged, true);
            app.Copy_Values_B.Tooltip = {'Copy the values of the first association and dissociation rate constants to all other cells'};
            app.Copy_Values_B.Text = ' ';
            app.LigandCTab = uitab(app.TabGroup2);
            app.LigandCTab.Title = 'Ligand C';
            app.Image_Tab_LigandC = uiimage(app.LigandCTab);
            app.Image_Tab_LigandC.ImageSource = 'MK_Tab2_C1x1.png';
            app.Panel_7 = uipanel(app.LigandCTab);
            app.GridLayout2_7 = uigridlayout(app.Panel_7);
            app.Kon_LigandC_R1_L1 = uieditfield(app.GridLayout2_7, 'numeric');
            app.Kon_LigandC_R1_L1.ValueChangedFcn = createCallbackFcn(app, @Kon_LigandC_R1_L1ValueChanged, true);
            app.Kon_LigandC_R1_L1.Tooltip = {'Marked association rate constant (1/Ms)'};
            app.Kon_LigandC_R1_L1.Value = 1000000;
            app.Koff_LigandC_R1_L1 = uieditfield(app.GridLayout2_7, 'numeric');
            app.Koff_LigandC_R1_L1.ValueChangedFcn = createCallbackFcn(app, @Koff_LigandC_R1_L1ValueChanged, true);
            app.Koff_LigandC_R1_L1.Tooltip = {'Marked dissociation rate constant (1/s)'};
            app.Koff_LigandC_R1_L1.Value = 1;
            app.Kon_LigandC_R2_L1 = uieditfield(app.GridLayout2_7, 'numeric');
            app.Kon_LigandC_R2_L1.ValueChangedFcn = createCallbackFcn(app, @Kon_LigandC_R2_L1ValueChanged, true);
            app.Kon_LigandC_R2_L1.Tooltip = {'Marked association rate constant (1/Ms)'};
            app.Kon_LigandC_R2_L1.Value = 1000000;
            app.Koff_LigandC_R2_L1 = uieditfield(app.GridLayout2_7, 'numeric');
            app.Koff_LigandC_R2_L1.ValueChangedFcn = createCallbackFcn(app, @Koff_LigandC_R2_L1ValueChanged, true);
            app.Koff_LigandC_R2_L1.Tooltip = {'Marked dissociation rate constant (1/s)'};
            app.Koff_LigandC_R2_L1.Value = 1;
            app.Kon_LigandC_R3_L1 = uieditfield(app.GridLayout2_7, 'numeric');
            app.Kon_LigandC_R3_L1.ValueChangedFcn = createCallbackFcn(app, @Kon_LigandC_R3_L1ValueChanged, true);
            app.Kon_LigandC_R3_L1.Tooltip = {'Marked association rate constant (1/Ms)'};
            app.Kon_LigandC_R3_L1.Value = 1000000;
            app.Koff_LigandC_R3_L1 = uieditfield(app.GridLayout2_7, 'numeric');
            app.Koff_LigandC_R3_L1.ValueChangedFcn = createCallbackFcn(app, @Koff_LigandC_R3_L1ValueChanged, true);
            app.Koff_LigandC_R3_L1.Tooltip = {'Marked dissociation rate constant (1/s)'};
            app.Koff_LigandC_R3_L1.Value = 1;
            app.Panel_8 = uipanel(app.LigandCTab);
            app.GridLayout2_8 = uigridlayout(app.Panel_8);
            app.Kon_LigandC_R1_L2 = uieditfield(app.GridLayout2_8, 'numeric');
            app.Kon_LigandC_R1_L2.ValueChangedFcn = createCallbackFcn(app, @Kon_LigandC_R1_L2ValueChanged, true);
            app.Kon_LigandC_R1_L2.Tooltip = {'Marked association rate constant (1/Ms)'};
            app.Kon_LigandC_R1_L2.Value = 1000000;
            app.Koff_LigandC_R1_L2 = uieditfield(app.GridLayout2_8, 'numeric');
            app.Koff_LigandC_R1_L2.ValueChangedFcn = createCallbackFcn(app, @Koff_LigandC_R1_L2ValueChanged, true);
            app.Koff_LigandC_R1_L2.Tooltip = {'Marked dissociation rate constant (1/s)'};
            app.Koff_LigandC_R1_L2.Value = 1;
            app.Kon_LigandC_R2_L2 = uieditfield(app.GridLayout2_8, 'numeric');
            app.Kon_LigandC_R2_L2.ValueChangedFcn = createCallbackFcn(app, @Kon_LigandC_R2_L2ValueChanged, true);
            app.Kon_LigandC_R2_L2.Tooltip = {'Marked association rate constant (1/Ms)'};
            app.Kon_LigandC_R2_L2.Value = 1000000;
            app.Koff_LigandC_R2_L2 = uieditfield(app.GridLayout2_8, 'numeric');
            app.Koff_LigandC_R2_L2.ValueChangedFcn = createCallbackFcn(app, @Koff_LigandC_R2_L2ValueChanged, true);
            app.Koff_LigandC_R2_L2.Tooltip = {'Marked dissociation rate constant (1/s)'};
            app.Koff_LigandC_R2_L2.Value = 1;
            app.Kon_LigandC_R3_L2 = uieditfield(app.GridLayout2_8, 'numeric');
            app.Kon_LigandC_R3_L2.ValueChangedFcn = createCallbackFcn(app, @Kon_LigandC_R3_L2ValueChanged, true);
            app.Kon_LigandC_R3_L2.Tooltip = {'Marked association rate constant (1/Ms)'};
            app.Kon_LigandC_R3_L2.Value = 1000000;
            app.Koff_LigandC_R3_L2 = uieditfield(app.GridLayout2_8, 'numeric');
            app.Koff_LigandC_R3_L2.ValueChangedFcn = createCallbackFcn(app, @Koff_LigandC_R3_L2ValueChanged, true);
            app.Koff_LigandC_R3_L2.Tooltip = {'Marked dissociation rate constant (1/s)'};
            app.Koff_LigandC_R3_L2.Value = 1;
            app.Panel_9 = uipanel(app.LigandCTab);
            app.GridLayout2_9 = uigridlayout(app.Panel_9);
            app.Kon_LigandC_R1_L3 = uieditfield(app.GridLayout2_9, 'numeric');
            app.Kon_LigandC_R1_L3.ValueChangedFcn = createCallbackFcn(app, @Kon_LigandC_R1_L3ValueChanged, true);
            app.Kon_LigandC_R1_L3.Tooltip = {'Marked association rate constant (1/Ms)'};
            app.Kon_LigandC_R1_L3.Value = 1000000;
            app.Koff_LigandC_R1_L3 = uieditfield(app.GridLayout2_9, 'numeric');
            app.Koff_LigandC_R1_L3.ValueChangedFcn = createCallbackFcn(app, @Koff_LigandC_R1_L3ValueChanged, true);
            app.Koff_LigandC_R1_L3.Tooltip = {'Marked dissociation rate constant (1/s)'};
            app.Koff_LigandC_R1_L3.Value = 1;
            app.Kon_LigandC_R2_L3 = uieditfield(app.GridLayout2_9, 'numeric');
            app.Kon_LigandC_R2_L3.ValueChangedFcn = createCallbackFcn(app, @Kon_LigandC_R2_L3ValueChanged, true);
            app.Kon_LigandC_R2_L3.Tooltip = {'Marked association rate constant (1/Ms)'};
            app.Kon_LigandC_R2_L3.Value = 1000000;
            app.Koff_LigandC_R2_L3 = uieditfield(app.GridLayout2_9, 'numeric');
            app.Koff_LigandC_R2_L3.ValueChangedFcn = createCallbackFcn(app, @Koff_LigandC_R2_L3ValueChanged, true);
            app.Koff_LigandC_R2_L3.Tooltip = {'Marked dissociation rate constant (1/s)'};
            app.Koff_LigandC_R2_L3.Value = 1;
            app.Kon_LigandC_R3_L3 = uieditfield(app.GridLayout2_9, 'numeric');
            app.Kon_LigandC_R3_L3.ValueChangedFcn = createCallbackFcn(app, @Kon_LigandC_R3_L3ValueChanged, true);
            app.Kon_LigandC_R3_L3.Tooltip = {'Marked association rate constant (1/Ms)'};
            app.Kon_LigandC_R3_L3.Value = 1000000;
            app.Koff_LigandC_R3_L3 = uieditfield(app.GridLayout2_9, 'numeric');
            app.Koff_LigandC_R3_L3.ValueChangedFcn = createCallbackFcn(app, @Koff_LigandC_R3_L3ValueChanged, true);
            app.Koff_LigandC_R3_L3.Tooltip = {'Marked dissociation rate constant (1/s)'};
            app.Koff_LigandC_R3_L3.Value = 1;
            app.CheckBox_3 = uicheckbox(app.LigandCTab);
            app.CheckBox_3.Text = ' ';
            app.LinkerLength1_LC = uieditfield(app.LigandCTab, 'numeric');
            app.LinkerLength1_LC.ValueChangedFcn = createCallbackFcn(app, @LinkerLength1_LCValueChanged, true);
            app.LinkerLength1_LC.Tooltip = {'First linker contour length (A)'};
            app.LinkerLength1_LC.Value = 5;
            app.LinkerLength2_LC = uieditfield(app.LigandCTab, 'numeric');
            app.LinkerLength2_LC.ValueChangedFcn = createCallbackFcn(app, @LinkerLength2_LCValueChanged, true);
            app.LinkerLength2_LC.Tooltip = {'Second linker contour length (A)'};
            app.LinkerLength2_LC.Value = 5;
            app.EditFieldLabel_3 = uilabel(app.LigandCTab);
            app.EditFieldLabel_3.Text = ' ';
            app.MolecularWeight_LC = uieditfield(app.LigandCTab, 'numeric');
            app.MolecularWeight_LC.ValueChangedFcn = createCallbackFcn(app, @MolecularWeight_LCValueChanged, true);
            app.MolecularWeight_LC.Tooltip = {'Molecular weight of Ligand C in g/mol'};
            app.MolecularWeight_LC.Value = 10000;
            app.EditField_2Label_3 = uilabel(app.LigandCTab);
            app.EditField_2Label_3.Text = ' ';
            app.UnitDiameter1_LC = uieditfield(app.LigandCTab, 'numeric');
            app.UnitDiameter1_LC.ValueChangedFcn = createCallbackFcn(app, @UnitDiameter1_LCValueChanged, true);
            app.UnitDiameter1_LC.Tooltip = {'First Ligand-unit diameter (A)'};
            app.UnitDiameter1_LC.Value = 10;
            app.EditField_3Label_3 = uilabel(app.LigandCTab);
            app.EditField_3Label_3.Text = ' ';
            app.UnitDiameter2_LC = uieditfield(app.LigandCTab, 'numeric');
            app.UnitDiameter2_LC.ValueChangedFcn = createCallbackFcn(app, @UnitDiameter2_LCValueChanged, true);
            app.UnitDiameter2_LC.Tooltip = {'Second Ligand-unit diameter (A)'};
            app.UnitDiameter2_LC.Value = 10;
            app.EditField_4Label_3 = uilabel(app.LigandCTab);
            app.EditField_4Label_3.Text = ' ';
            app.UnitDiameter3_LC = uieditfield(app.LigandCTab, 'numeric');
            app.UnitDiameter3_LC.ValueChangedFcn = createCallbackFcn(app, @UnitDiameter3_LCValueChanged, true);
            app.UnitDiameter3_LC.Tooltip = {'Third Ligand-unit diameter (A)'};
            app.UnitDiameter3_LC.Value = 10;
            app.EditField_7Label_3 = uilabel(app.LigandCTab);
            app.EditField_7Label_3.Text = ' ';
            app.PersistanceLength1_LC = uieditfield(app.LigandCTab, 'numeric');
            app.PersistanceLength1_LC.ValueChangedFcn = createCallbackFcn(app, @PersistanceLength1_LCValueChanged, true);
            app.PersistanceLength1_LC.Tooltip = {'First linker persistence length (A)'};
            app.PersistanceLength1_LC.Value = 5;
            app.EditField_8Label_3 = uilabel(app.LigandCTab);
            app.EditField_8Label_3.Text = ' ';
            app.PersistanceLength2_LC = uieditfield(app.LigandCTab, 'numeric');
            app.PersistanceLength2_LC.ValueChangedFcn = createCallbackFcn(app, @PersistanceLength2_LCValueChanged, true);
            app.PersistanceLength2_LC.Tooltip = {'Second linker persistence length (A)'};
            app.PersistanceLength2_LC.Value = 5;
            app.MWLabel_3 = uilabel(app.LigandCTab);
            app.MWLabel_3.Text = 'MW';
            app.Copy_Values_C = uicheckbox(app.LigandCTab);
            app.Copy_Values_C.ValueChangedFcn = createCallbackFcn(app, @Copy_Values_CValueChanged, true);
            app.Copy_Values_C.Tooltip = {'Copy the values of the first association and dissociation rate constants to all other cells'};
            app.Copy_Values_C.Text = ' ';
            app.ReceptorTab = uitab(app.TabGroup2);
            app.ReceptorTab.Title = 'Receptor';
            app.Image_Tab_Receptor = uiimage(app.ReceptorTab);
            app.Image_Tab_Receptor.ImageSource = 'GUI Rec_Tab_R1.png';
            app.LinkerLength1_Receptor = uieditfield(app.ReceptorTab, 'numeric');
            app.LinkerLength1_Receptor.ValueChangedFcn = createCallbackFcn(app, @LinkerLength1_ReceptorValueChanged, true);
            app.LinkerLength1_Receptor.Tooltip = {'First linker contour length (A)'};
            app.LinkerLength1_Receptor.Value = 5;
            app.LinkerLength2_Receptor = uieditfield(app.ReceptorTab, 'numeric');
            app.LinkerLength2_Receptor.ValueChangedFcn = createCallbackFcn(app, @LinkerLength2_ReceptorValueChanged, true);
            app.LinkerLength2_Receptor.Tooltip = {'Second linker contour length (A)'};
            app.LinkerLength2_Receptor.Value = 5;
            app.EditField_2Label_4 = uilabel(app.ReceptorTab);
            app.EditField_2Label_4.Text = ' ';
            app.UnitDiameter1_Receptor = uieditfield(app.ReceptorTab, 'numeric');
            app.UnitDiameter1_Receptor.ValueChangedFcn = createCallbackFcn(app, @UnitDiameter1_ReceptorValueChanged, true);
            app.UnitDiameter1_Receptor.Tooltip = {'First Receptor-unit diameter (A)'};
            app.UnitDiameter1_Receptor.Value = 10;
            app.EditField_3Label_4 = uilabel(app.ReceptorTab);
            app.EditField_3Label_4.Text = ' ';
            app.UnitDiameter2_Receptor = uieditfield(app.ReceptorTab, 'numeric');
            app.UnitDiameter2_Receptor.ValueChangedFcn = createCallbackFcn(app, @UnitDiameter2_ReceptorValueChanged, true);
            app.UnitDiameter2_Receptor.Tooltip = {'Second Receptor-unit diameter (A)'};
            app.UnitDiameter2_Receptor.Value = 10;
            app.EditField_4Label_4 = uilabel(app.ReceptorTab);
            app.EditField_4Label_4.Text = ' ';
            app.UnitDiameter3_Receptor = uieditfield(app.ReceptorTab, 'numeric');
            app.UnitDiameter3_Receptor.ValueChangedFcn = createCallbackFcn(app, @UnitDiameter3_ReceptorValueChanged, true);
            app.UnitDiameter3_Receptor.Tooltip = {'Third Receptor-unit diameter (A)'};
            app.UnitDiameter3_Receptor.Value = 10;
            app.EditField_7Label_4 = uilabel(app.ReceptorTab);
            app.EditField_7Label_4.Text = ' ';
            app.PersistanceLength1_Receptor = uieditfield(app.ReceptorTab, 'numeric');
            app.PersistanceLength1_Receptor.ValueChangedFcn = createCallbackFcn(app, @PersistanceLength1_ReceptorValueChanged, true);
            app.PersistanceLength1_Receptor.Tooltip = {'First linker persistence length (A)'};
            app.PersistanceLength1_Receptor.Value = 5;
            app.EditField_8Label_4 = uilabel(app.ReceptorTab);
            app.EditField_8Label_4.Text = ' ';
            app.PersistanceLength2_Receptor = uieditfield(app.ReceptorTab, 'numeric');
            app.PersistanceLength2_Receptor.ValueChangedFcn = createCallbackFcn(app, @PersistanceLength2_ReceptorValueChanged, true);
            app.PersistanceLength2_Receptor.Tooltip = {'Second linker persistence length (A)'};
            app.PersistanceLength2_Receptor.Value = 5;
            app.MolecularWeight_Receptor = uieditfield(app.ReceptorTab, 'numeric');
            app.MolecularWeight_Receptor.ValueChangedFcn = createCallbackFcn(app, @MolecularWeight_ReceptorValueChanged, true);
            app.MolecularWeight_Receptor.Tooltip = {'Molecular weight of the receptor in g/mol'};
            app.MolecularWeight_Receptor.Value = 10000;
            app.RU_Receptor = uieditfield(app.ReceptorTab, 'numeric');
            app.RU_Receptor.ValueChangedFcn = createCallbackFcn(app, @RU_ReceptorValueChanged, true);
            app.RU_Receptor.Tooltip = {'Receptor initial Response Units (option 1)'};
            app.RU_Receptor.Value = 100;
            app.ReceptorConcentration_Receptor_4 = uieditfield(app.ReceptorTab, 'numeric');
            app.ReceptorConcentration_Receptor_4.ValueChangedFcn = createCallbackFcn(app, @ReceptorConcentration_Receptor_4ValueChanged, true);
            app.ReceptorConcentration_Receptor_4.Tooltip = {'Receptor initial Molar concentration (option 2)'};
            app.ConcentrationsTab = uitab(app.TabGroup2);
            app.ConcentrationsTab.Title = 'Concentrations';
            app.UIAxes_Timeseries_plot = uiaxes(app.ConcentrationsTab);
            title(app.UIAxes_Timeseries_plot, 'Time series for ligand concentrations')
            xlabel(app.UIAxes_Timeseries_plot, 'Time (s)')
            ylabel(app.UIAxes_Timeseries_plot, 'Ligand (M)')
            app.Table_Timepoints = uitable(app.ConcentrationsTab);
            app.Table_Timepoints.ColumnName = {'1'; 'Ligand A (M)'; 'Ligand B (M)'; 'Ligand C (M)'};
            app.Table_Timepoints.RowName = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'};
            app.Table_Timepoints.ColumnSortable = true;
            app.Table_Timepoints.ColumnEditable = true;
            app.Table_Timepoints.CellEditCallback = createCallbackFcn(app, @Table_TimepointsCellEdit, true);
            app.Table_Timepoints.DisplayDataChangedFcn = createCallbackFcn(app, @Table_TimepointsDisplayDataChanged, true);
            app.AddNewTimepointButton = uibutton(app.ConcentrationsTab, 'push');
            app.AddNewTimepointButton.ButtonPushedFcn = createCallbackFcn(app, @AddNewTimepointButtonPushed, true);
            app.AddNewTimepointButton.Tooltip = {'Adds the inputted concentration for the time interval'};
            app.AddNewTimepointButton.Text = 'Add New Timepoint';
            app.ResettimepointsButton = uibutton(app.ConcentrationsTab, 'push');
            app.ResettimepointsButton.ButtonPushedFcn = createCallbackFcn(app, @ResettimepointsButtonPushed, true);
            app.ResettimepointsButton.Tooltip = {'Clears all the timepoints'};
            app.ResettimepointsButton.Text = 'Reset timepoints';
            app.TimepointssEditFieldLabel = uilabel(app.ConcentrationsTab);
            app.TimepointssEditFieldLabel.Text = 'Time points (s)';
            app.TimepointsEditField = uieditfield(app.ConcentrationsTab, 'numeric');
            app.TimepointsEditField.Tooltip = {'End timepoint of an intervention in seconds'};
            app.LigandAMEditFieldLabel = uilabel(app.ConcentrationsTab);
            app.LigandAMEditFieldLabel.Text = 'Ligand A (M)';
            app.LigandA_EditField = uieditfield(app.ConcentrationsTab, 'numeric');
            app.LigandA_EditField.Tooltip = {'Molar concentration of Ligand A  '};
            app.LigandBMEditFieldLabel = uilabel(app.ConcentrationsTab);
            app.LigandBMEditFieldLabel.Text = 'Ligand B (M)';
            app.LigandB_EditField = uieditfield(app.ConcentrationsTab, 'numeric');
            app.LigandB_EditField.Tooltip = {'Molar concentration of Ligand B'};
            app.LigandCMEditFieldLabel = uilabel(app.ConcentrationsTab);
            app.LigandCMEditFieldLabel.Text = 'Ligand C (M)';
            app.LigandC_EditField = uieditfield(app.ConcentrationsTab, 'numeric');
            app.LigandC_EditField.Tooltip = {'Molar concentration of Ligand C'};
            app.RerunButton = uibutton(app.LeftPanel, 'push');
            app.RerunButton.ButtonPushedFcn = createCallbackFcn(app, @RerunButtonPushed, true);
            app.RerunButton.Enable = 'off';
            app.RerunButton.Tooltip = {'After changing concentration or rate-constants rerun is sufficient '};
            app.RerunButton.Text = 'Rerun';
            app.InitiateandRunButton = uibutton(app.LeftPanel, 'push');
            app.InitiateandRunButton.ButtonPushedFcn = createCallbackFcn(app, @InitiateandRunButtonPushed, true);
            app.InitiateandRunButton.Tooltip = {'Initiation is required after changing the topology or linker parameters'};
            app.InitiateandRunButton.Text = 'Initiate and Run';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.OverviewTab = uitab(app.TabGroup);
            app.OverviewTab.Title = 'Overview';
            app.Axis_MassCurve_main = uiaxes(app.OverviewTab);
            title(app.Axis_MassCurve_main, 'Overall Binding Response and Microstate Composition')
            xlabel(app.Axis_MassCurve_main, {'Time (s)'; ''})
            ylabel(app.Axis_MassCurve_main, 'Binding Response')
            app.Axis_GroupedStates_main = uiaxes(app.OverviewTab);
            title(app.Axis_GroupedStates_main, 'Grouped States')
            xlabel(app.Axis_GroupedStates_main, 'Time (s)')
            ylabel(app.Axis_GroupedStates_main, 'Binding Response')
            app.Axis_Network_main = uiaxes(app.OverviewTab);
            title(app.Axis_Network_main, 'Grouped States')
            app.Axis_Network_main.XTick = [];
            app.Axis_Network_main.YTick = [];
            app.CheckBox_Main_M = uicheckbox(app.OverviewTab);
            app.CheckBox_Main_M.ValueChangedFcn = createCallbackFcn(app, @CheckBox_Main_MValueChanged, true);
            app.CheckBox_Main_M.Tooltip = {'Ligand molar concentration (M)'};
            app.CheckBox_Main_M.Text = 'Molar Concentration';
            app.CheckBox_Main_RU = uicheckbox(app.OverviewTab);
            app.CheckBox_Main_RU.ValueChangedFcn = createCallbackFcn(app, @CheckBox_Main_RUValueChanged, true);
            app.CheckBox_Main_RU.Tooltip = {'SPR Response units equivalent to 1pg'; ''};
            app.CheckBox_Main_RU.Text = 'RU';
            app.CheckBox_Main_RU.Value = true;
            app.Slider_main = uislider(app.OverviewTab);
            app.Slider_main.ValueChangedFcn = createCallbackFcn(app, @Slider_mainValueChanged, true);
            app.BindingResponseunitsLabel = uilabel(app.OverviewTab);
            app.BindingResponseunitsLabel.Text = 'Binding Response units:';
            app.TotalSignalTab = uitab(app.TabGroup);
            app.TotalSignalTab.Title = 'Total Signal';
            app.Axis_MassCurve_separate = uiaxes(app.TotalSignalTab);
            title(app.Axis_MassCurve_separate, 'Overall Binding Response')
            xlabel(app.Axis_MassCurve_separate, {'Time (s)'; ''})
            ylabel(app.Axis_MassCurve_separate, 'Binding Response')
            app.CheckBox_TotalSignal_M = uicheckbox(app.TotalSignalTab);
            app.CheckBox_TotalSignal_M.ValueChangedFcn = createCallbackFcn(app, @CheckBox_TotalSignal_MValueChanged, true);
            app.CheckBox_TotalSignal_M.Tooltip = {'Ligand molar concentration (M)'};
            app.CheckBox_TotalSignal_M.Text = 'Molar Concentration';
            app.CheckBox_TotalSignal_RU = uicheckbox(app.TotalSignalTab);
            app.CheckBox_TotalSignal_RU.ValueChangedFcn = createCallbackFcn(app, @CheckBox_TotalSignal_RUValueChanged, true);
            app.CheckBox_TotalSignal_RU.Tooltip = {'SPR Response units equivalent to 1pg'; ''};
            app.CheckBox_TotalSignal_RU.Text = 'RU';
            app.CheckBox_TotalSignal_RU.Value = true;
            app.SaveFigureButton = uibutton(app.TotalSignalTab, 'push');
            app.SaveFigureButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButtonPushed, true);
            app.SaveFigureButton.Text = 'Save Figure';
            app.BindingResponseunitsLabel_2 = uilabel(app.TotalSignalTab);
            app.BindingResponseunitsLabel_2.Text = 'Binding Response units:';
            app.MicrostatesTab = uitab(app.TabGroup);
            app.MicrostatesTab.Title = 'Microstates';
            app.Axis_SignalComponents_separate = uiaxes(app.MicrostatesTab);
            title(app.Axis_SignalComponents_separate, 'Microstate Composition of Total Signal')
            xlabel(app.Axis_SignalComponents_separate, 'Time (s)')
            ylabel(app.Axis_SignalComponents_separate, 'Binding Response')
            app.CheckBox_MicroStates_M = uicheckbox(app.MicrostatesTab);
            app.CheckBox_MicroStates_M.ValueChangedFcn = createCallbackFcn(app, @CheckBox_MicroStates_MValueChanged, true);
            app.CheckBox_MicroStates_M.Tooltip = {'Ligand molar concentration (M)'};
            app.CheckBox_MicroStates_M.Text = 'Molar Concentration';
            app.CheckBox_MicroStates_RU = uicheckbox(app.MicrostatesTab);
            app.CheckBox_MicroStates_RU.ValueChangedFcn = createCallbackFcn(app, @CheckBox_MicroStates_RUValueChanged, true);
            app.CheckBox_MicroStates_RU.Tooltip = {'SPR Response units equivalent to 1pg'; ''};
            app.CheckBox_MicroStates_RU.Text = 'RU';
            app.CheckBox_MicroStates_RU.Value = true;
            app.SaveFigureButton_2 = uibutton(app.MicrostatesTab, 'push');
            app.SaveFigureButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButton_2Pushed, true);
            app.SaveFigureButton_2.Text = 'Save Figure';
            app.BindingResponseunitsLabel_3 = uilabel(app.MicrostatesTab);
            app.BindingResponseunitsLabel_3.Text = 'Binding Response units:';
            app.GroupedbyClassTab = uitab(app.TabGroup);
            app.GroupedbyClassTab.Title = 'Grouped by Class';
            app.Axis_GroupedStates_separate = uiaxes(app.GroupedbyClassTab);
            title(app.Axis_GroupedStates_separate, 'Microstates Grouped by Class (Ligand or Valency)')
            xlabel(app.Axis_GroupedStates_separate, 'Time (s)')
            ylabel(app.Axis_GroupedStates_separate, 'Binding Response')
            app.CheckBox_Grouped_M = uicheckbox(app.GroupedbyClassTab);
            app.CheckBox_Grouped_M.ValueChangedFcn = createCallbackFcn(app, @CheckBox_Grouped_MValueChanged, true);
            app.CheckBox_Grouped_M.Tooltip = {'Ligand molar concentration (M)'};
            app.CheckBox_Grouped_M.Text = 'Molar Concentration';
            app.CheckBox_Grouped_RU = uicheckbox(app.GroupedbyClassTab);
            app.CheckBox_Grouped_RU.ValueChangedFcn = createCallbackFcn(app, @CheckBox_Grouped_RUValueChanged, true);
            app.CheckBox_Grouped_RU.Tooltip = {'SPR Response units equivalent to 1pg'; ''};
            app.CheckBox_Grouped_RU.Text = 'RU';
            app.CheckBox_Grouped_RU.Value = true;
            app.SaveFigureButton_3 = uibutton(app.GroupedbyClassTab, 'push');
            app.SaveFigureButton_3.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButton_3Pushed, true);
            app.SaveFigureButton_3.Text = 'Save Figure';
            app.ClassesDropDownLabel = uilabel(app.GroupedbyClassTab);
            app.ClassesDropDownLabel.Text = 'Classes';
            app.ClassesDropDown = uidropdown(app.GroupedbyClassTab);
            app.ClassesDropDown.Items = {'Ligand', 'Valency', 'Mixed'};
            app.ClassesDropDown.ValueChangedFcn = createCallbackFcn(app, @ClassesDropDownValueChanged, true);
            app.ClassesDropDown.Value = 'Valency';
            app.BindingResponseunitsLabel_4 = uilabel(app.GroupedbyClassTab);
            app.BindingResponseunitsLabel_4.Text = 'Binding Response units:';
            app.NetworkDynamicsTab = uitab(app.TabGroup);
            app.NetworkDynamicsTab.Title = 'Network Dynamics';
            app.Axis_Network_separate = uiaxes(app.NetworkDynamicsTab);
            title(app.Axis_Network_separate, 'Time-Resolved Network of Microstates')
            app.Axis_Network_separate.XTick = [];
            app.Axis_Network_separate.YTick = [];
            app.CheckBox_Netw_M = uicheckbox(app.NetworkDynamicsTab);
            app.CheckBox_Netw_M.ValueChangedFcn = createCallbackFcn(app, @CheckBox_Netw_MValueChanged, true);
            app.CheckBox_Netw_M.Enable = 'off';
            app.CheckBox_Netw_M.Visible = 'off';
            app.CheckBox_Netw_M.Text = 'Molar Concentration';
            app.CheckBox_Netw_RU = uicheckbox(app.NetworkDynamicsTab);
            app.CheckBox_Netw_RU.ValueChangedFcn = createCallbackFcn(app, @CheckBox_Netw_RUValueChanged, true);
            app.CheckBox_Netw_RU.Enable = 'off';
            app.CheckBox_Netw_RU.Visible = 'off';
            app.CheckBox_Netw_RU.Text = 'RU';
            app.CheckBox_Netw_RU.Value = true;
            app.ClassesDropDown_2Label = uilabel(app.NetworkDynamicsTab);
            app.ClassesDropDown_2Label.Text = 'Classes';
            app.ClassesDropDown_2 = uidropdown(app.NetworkDynamicsTab);
            app.ClassesDropDown_2.Items = {'Ligand', 'Valency'};
            app.ClassesDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ClassesDropDown_2ValueChanged2, true);
            app.ClassesDropDown_2.Tooltip = {'Accumulated state concentration for the given category'};
            app.ClassesDropDown_2.Value = 'Valency';
            app.TimesLabel = uilabel(app.NetworkDynamicsTab);
            app.TimesLabel.Text = 'Time (s)';
            app.Slider_separate = uislider(app.NetworkDynamicsTab);
            app.Slider_separate.ValueChangedFcn = createCallbackFcn(app, @Slider_separateValueChanged, true);
            app.BindingResponseunitsLabel_5 = uilabel(app.NetworkDynamicsTab);
            app.BindingResponseunitsLabel_5.Enable = 'off';
            app.BindingResponseunitsLabel_5.Visible = 'off';
            app.BindingResponseunitsLabel_5.Text = 'Binding Response units:';
            app.EffectiveConcentrationTab = uitab(app.TabGroup);
            app.EffectiveConcentrationTab.Title = 'Effective Concentration';
            app.UITable = uitable(app.EffectiveConcentrationTab);
            app.UITable.ColumnName = {'Effective conc. name'; 'Value (Molar)'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.EffectiveConcentrationsLabel = uilabel(app.EffectiveConcentrationTab);
            app.EffectiveConcentrationsLabel.Text = 'Effective Concentrations';
            app.UpdateTableButton = uibutton(app.EffectiveConcentrationTab, 'push');
            app.UpdateTableButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateTableButtonPushed, true);
            app.UpdateTableButton.Text = 'Update Table';
            app.AllinformationCheckBox = uicheckbox(app.EffectiveConcentrationTab);
            app.AllinformationCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllinformationCheckBoxValueChanged, true);
            app.AllinformationCheckBox.Tooltip = {'Detailed information for the effective concentration properties'};
            app.AllinformationCheckBox.Text = 'All information';
            app.PDFTab = uitab(app.TabGroup);
            app.PDFTab.Title = 'PDF';
            app.PDF_plot = uiaxes(app.PDFTab);
            title(app.PDF_plot, 'Receptor and ligand PDFs')
            xlabel(app.PDF_plot, 'End-to-end distance (A)')
            ylabel(app.PDF_plot, 'Probability')
            zlabel(app.PDF_plot, 'Z')
            app.DistanceALabel = uilabel(app.PDFTab);
            app.DistanceALabel.Text = 'Distance A';
            app.LigandPDF_Distance_input = uitextarea(app.PDFTab);
            app.LigandPDF_Distance_input.ValueChangedFcn = createCallbackFcn(app, @LigandPDF_Distance_inputValueChanged, true);
            app.LigandPDF_Distance_input.Tooltip = {'Distance values in newlines '};
            app.LigandDropDownLabel = uilabel(app.PDFTab);
            app.LigandDropDownLabel.Text = 'Ligand';
            app.LigandPDF_rank = uidropdown(app.PDFTab);
            app.LigandPDF_rank.Items = {'Ligand A', 'Ligand B', 'Ligand C'};
            app.LigandPDF_rank.ItemsData = {'1', '2', '3'};
            app.LigandPDF_rank.ValueChangedFcn = createCallbackFcn(app, @LigandPDF_rankValueChanged, true);
            app.LigandPDF_rank.Value = '1';
            app.LigandPDF_step = uidropdown(app.PDFTab);
            app.LigandPDF_step.Items = {'-- monovalent --', 'Position 1 -> 2', 'Position 2 -> 3', 'Position 1 -> 3'};
            app.LigandPDF_step.ItemsData = {'1', '2', '3', '4'};
            app.LigandPDF_step.ValueChangedFcn = createCallbackFcn(app, @LigandPDF_stepValueChanged, true);
            app.LigandPDF_step.Tooltip = {'Choose apropriate PDF to export ot inport  '};
            app.LigandPDF_step.Value = '1';
            app.ProbabiltyLabel = uilabel(app.PDFTab);
            app.ProbabiltyLabel.Text = 'Probabilty';
            app.LigandPDF_Values_input = uitextarea(app.PDFTab);
            app.LigandPDF_Values_input.ValueChangedFcn = createCallbackFcn(app, @LigandPDF_Values_inputValueChanged, true);
            app.LigandPDF_Values_input.Tooltip = {'Corresponding probabilities (automatically normalized to 1)'};
            app.DistanceALabel_2 = uilabel(app.PDFTab);
            app.DistanceALabel_2.Text = 'Distance A';
            app.ReceptorPDF_Distance_input = uitextarea(app.PDFTab);
            app.ReceptorPDF_Distance_input.ValueChangedFcn = createCallbackFcn(app, @ReceptorPDF_Distance_inputValueChanged, true);
            app.ReceptorPDF_Distance_input.Tooltip = {'Distance values in newlines '};
            app.ProbabiltyLabel_2 = uilabel(app.PDFTab);
            app.ProbabiltyLabel_2.Text = 'Probabilty';
            app.ReceptorPDF_values_input = uitextarea(app.PDFTab);
            app.ReceptorPDF_values_input.ValueChangedFcn = createCallbackFcn(app, @ReceptorPDF_values_inputValueChanged, true);
            app.ReceptorPDF_values_input.Tooltip = {'Corresponding probabilities (automatically normalized to 1)'};
            app.ReceptorPDF_step = uidropdown(app.PDFTab);
            app.ReceptorPDF_step.Items = {'-- monovalent --', 'Position 1 -> 2', 'Position 2 -> 3', 'Position 1 -> 3'};
            app.ReceptorPDF_step.ItemsData = {'1', '2', '3', '4'};
            app.ReceptorPDF_step.ValueChangedFcn = createCallbackFcn(app, @ReceptorPDF_stepValueChanged, true);
            app.ReceptorPDF_step.Tooltip = {'Choose apropriate PDF to export ot inport  '};
            app.ReceptorPDF_step.Value = '1';
            app.ReceptorLabel = uilabel(app.PDFTab);
            app.ReceptorLabel.Text = 'Receptor';
            app.PDF_Cumulative = uicheckbox(app.PDFTab);
            app.PDF_Cumulative.ValueChangedFcn = createCallbackFcn(app, @PDF_CumulativeValueChanged, true);
            app.PDF_Cumulative.Tooltip = {'Cumulative PDF for each r distance (A) or cross-sectional 3D PDF'};
            app.PDF_Cumulative.Text = 'Cumulative';
            app.LigandPDF_Update = uibutton(app.PDFTab, 'push');
            app.LigandPDF_Update.ButtonPushedFcn = createCallbackFcn(app, @LigandPDF_UpdateButtonPushed, true);
            app.LigandPDF_Update.Text = 'Update';
            app.ReceptorPDF_Update = uibutton(app.PDFTab, 'push');
            app.ReceptorPDF_Update.ButtonPushedFcn = createCallbackFcn(app, @ReceptorPDF_UpdateButtonPushed, true);
            app.ReceptorPDF_Update.Text = 'Update';
            app.ProgressPanel = uipanel(app.RightPanel);
            app.ProgressPanel.Title = 'Progress';
            app.ProgressButton = uibutton(app.ProgressPanel, 'push');
            app.ProgressButton.Text = '';
            app.ProgressLabel = uilabel(app.ProgressPanel);
            app.ProgressLabel.Text = 'Ready';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MVsim_10

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end